classdef Crisp_App_V9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfaceGraphiqueRobotCRISPUIFigure  matlab.ui.Figure
        TextArea                       matlab.ui.control.TextArea
        InitialisationButton           matlab.ui.control.Button
        ArrtButton                     matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        TabGroup_2                     matlab.ui.container.TabGroup
        CommandedirecteTab             matlab.ui.container.Tab
        TabGroup                       matlab.ui.container.TabGroup
        CartsienTab                    matlab.ui.container.Tab
        RoulisSlider_2Label            matlab.ui.control.Label
        RoulisSlider                   matlab.ui.control.Slider
        TangageSliderLabel             matlab.ui.control.Label
        TangageSlider                  matlab.ui.control.Slider
        LacetSliderLabel               matlab.ui.control.Label
        LacetSlider                    matlab.ui.control.Slider
        ZSliderLabel                   matlab.ui.control.Label
        ZSlider                        matlab.ui.control.Slider
        YSliderLabel                   matlab.ui.control.Label
        YSlider                        matlab.ui.control.Slider
        XSliderLabel                   matlab.ui.control.Label
        XSlider                        matlab.ui.control.Slider
        PositioninitialeButton         matlab.ui.control.Button
        mmLabel                        matlab.ui.control.Label
        mmEditField                    matlab.ui.control.NumericEditField
        mmEditField_2Label             matlab.ui.control.Label
        mmEditField_2                  matlab.ui.control.NumericEditField
        mmEditField_3Label             matlab.ui.control.Label
        mmEditField_3                  matlab.ui.control.NumericEditField
        EditField_10Label              matlab.ui.control.Label
        EditField_10                   matlab.ui.control.NumericEditField
        EditField_11Label              matlab.ui.control.Label
        EditField_11                   matlab.ui.control.NumericEditField
        EditField_12Label              matlab.ui.control.Label
        EditField_12                   matlab.ui.control.NumericEditField
        EnregistrercommezroButton      matlab.ui.control.Button
        ArticulaireTab                 matlab.ui.container.Tab
        Slider_4Label                  matlab.ui.control.Label
        Slider_4                       matlab.ui.control.Slider
        Slider_5Label                  matlab.ui.control.Label
        Slider_5                       matlab.ui.control.Slider
        Slider_6Label                  matlab.ui.control.Label
        Slider_6                       matlab.ui.control.Slider
        Slider_3Label                  matlab.ui.control.Label
        Slider_3                       matlab.ui.control.Slider
        Slider_2Label                  matlab.ui.control.Label
        Slider_2                       matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        PositioninitialeButton_2       matlab.ui.control.Button
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        EditField_2Label               matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3Label               matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_4Label               matlab.ui.control.Label
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_5Label               matlab.ui.control.Label
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_6Label               matlab.ui.control.Label
        EditField_6                    matlab.ui.control.NumericEditField
        EnregistrercommezroButton_2    matlab.ui.control.Button
        TrajectoireTab                 matlab.ui.container.Tab
        TrajectoireList                matlab.ui.control.ListBox
        LoopingSwitch                  matlab.ui.control.Switch
        JouerlatrajectoireButton       matlab.ui.control.Button
        TempsentrelespointsEditFieldLabel  matlab.ui.control.Label
        TempsentrelespointsEditField   matlab.ui.control.EditField
        ArrterlatrajectoireButton      matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        DegrsdelibertTab_2             matlab.ui.container.Tab
        PectoralLabel                  matlab.ui.control.Label
        PectoralSwitch                 matlab.ui.control.Switch
        pauleLabel                     matlab.ui.control.Label
        EpauleSwitch                   matlab.ui.control.Switch
        BrasLabel                      matlab.ui.control.Label
        BrasSwitch                     matlab.ui.control.Switch
        CoudeLabel                     matlab.ui.control.Label
        CoudeSwitch                    matlab.ui.control.Switch
        AvantbrasLabel                 matlab.ui.control.Label
        AvantbrasSwitch                matlab.ui.control.Switch
        PoignetLabel                   matlab.ui.control.Label
        PoignetSwitch                  matlab.ui.control.Switch
        MainLabel                      matlab.ui.control.Label
        OutilSwitch                    matlab.ui.control.Switch
        TouslesDDLSwitchLabel          matlab.ui.control.Label
        TouslesDDLSwitch               matlab.ui.control.Switch
        ParamtresTab_3                 matlab.ui.container.Tab
        VitesseMaximaleEditField_2Label  matlab.ui.control.Label
        VitesseMaximaleEditField       matlab.ui.control.NumericEditField
        ParamSelectionDDL              matlab.ui.control.DropDown
        CourantdedmarEditField_2Label  matlab.ui.control.Label
        CourantdedmarEditField         matlab.ui.control.NumericEditField
        CouplePEditField_2Label        matlab.ui.control.Label
        CouplePEditField               matlab.ui.control.NumericEditField
        CoupleIEditField_2Label        matlab.ui.control.Label
        CoupleIEditField               matlab.ui.control.NumericEditField
        VitessePEditField_2Label       matlab.ui.control.Label
        VitessePEditField              matlab.ui.control.NumericEditField
        VitesseIEditField_2Label       matlab.ui.control.Label
        VitesseIEditField              matlab.ui.control.NumericEditField
        PositionPEditField_2Label      matlab.ui.control.Label
        PositionPEditField             matlab.ui.control.NumericEditField
        EnregistrementdesnouveauxparametresButton  matlab.ui.control.Button
        CommunicationTab               matlab.ui.container.Tab
        PortEditFieldLabel             matlab.ui.control.Label
        PortEditField                  matlab.ui.control.EditField
        TabGroup2_2                    matlab.ui.container.TabGroup
        ApprentissageTab_3             matlab.ui.container.Tab
        ApprentissagePos               matlab.ui.control.TextArea
        RecPos                         matlab.ui.control.Button
        Save                           matlab.ui.control.Button
        DeletePos                      matlab.ui.control.Button
        SetTime                        matlab.ui.control.Button
        Play                           matlab.ui.control.Button
        Download                       matlab.ui.control.Button
        LabelTrajectory                matlab.ui.control.Label
        DonnesTab                      matlab.ui.container.Tab
        PositionSwitchLabel            matlab.ui.control.Label
        PositionSwitch                 matlab.ui.control.Switch
        AcclrationLabel                matlab.ui.control.Label
        AccelerationSwitch             matlab.ui.control.Switch
        VitesseLabel                   matlab.ui.control.Label
        VitesseSwitch                  matlab.ui.control.Switch
        TouteslesvariablesSwitchLabel  matlab.ui.control.Label
        TouteslesvariablesSwitch       matlab.ui.control.Switch
        EnregistementSwitchLabel       matlab.ui.control.Label
        EnregistementSwitch            matlab.ui.control.ToggleSwitch
        NomdufichierEditFieldLabel     matlab.ui.control.Label
        NomdufichierEditField          matlab.ui.control.EditField
        DossierEditFieldLabel          matlab.ui.control.Label
        DossierEditField               matlab.ui.control.EditField
        SlectionnerdossierButton       matlab.ui.control.Button
        GraphiquetempsrelButton        matlab.ui.control.Button
        OutilTab                       matlab.ui.container.Tab
        Image3                         matlab.ui.control.Image
        d7mmEditFieldLabel             matlab.ui.control.Label
        d7mmEditField                  matlab.ui.control.NumericEditField
        a7mmEditFieldLabel             matlab.ui.control.Label
        a7mmEditField                  matlab.ui.control.NumericEditField
        Switch                         matlab.ui.control.Switch
        CrispLogoImage                 matlab.ui.control.Image
        MaininactiveButton             matlab.ui.control.StateButton
        tatdurobotLabel                matlab.ui.control.Label
        EtatDEL                        matlab.ui.control.Lamp
        Terminal                       matlab.ui.control.ListBox
        DmarrageButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        myTimer % effectue des callbacks à intervalle réguliers
        % Acquisition des répertoires des fonctions du programme
        
        RecordTimer %effectue les callbabks a intervalles reguliers
        RealTimeGraphAppCrispV1 %Application pop-up pour les options de graphique en temps réel
        
        % Génération des path des répertoires de l'application
        pathInit = addpath(genpath('  '));
        pathControl = addpath(genpath('Control'));
        PathComm = addpath(genpath('Communication'));
        PathPeterCorke = addpath(genpath('PeterCorke'));
        PathTraj = addpath(genpath('Trajectoires'));
        PathImages = addpath(genpath('Images'));
        PathSTL = addpath('.STL');
        
        p = [NaN NaN NaN NaN NaN NaN];%Position en ticks
        
        
        % Parametres
        Vmax = [NaN NaN NaN NaN NaN NaN];
        Idemar = [NaN NaN NaN NaN NaN NaN];
        CoupleP = [NaN NaN NaN NaN NaN NaN];
        CoupleI = [NaN NaN NaN NaN NaN NaN];
        VitesseP = [NaN NaN NaN NaN NaN NaN];
        VitesseI = [NaN NaN NaN NaN NaN NaN];
        PositionP = [NaN NaN NaN NaN NaN NaN];
        
        % Paramètres pour la représentation 3D du robot avec fichiers STL
        V = []; 
        F = []; 
        S = [];
    end
    
    methods (Access = private)
        
        function [] = update_q(app)
             % Mise à jour des éléments graphiques en fonction de la
             % position articulaire q (en rad) actuelle
             global q 
             %% Has to have an error handler
             app.Slider.Value = q(1);
             app.EditField.Value = 180/pi*q(1);
             app.Slider_2.Value = q(2);
             app.EditField_2.Value = 180/pi*q(2);
             app.Slider_3.Value = q(3);
             app.EditField_3.Value = 180/pi*q(3);
             app.Slider_4.Value = q(4);
             app.EditField_4.Value = 180/pi*q(4);
             app.Slider_5.Value = q(5);
             app.EditField_5.Value = 180/pi*q(5);
             app.Slider_6.Value = q(6);
             app.EditField_6.Value = 180/pi*q(6);
             q(7) = 0;
        end
        
        function [] = update_x(app)
             % Mise à jour des éléments graphiques en fonction de la
             % position cartésienne x (en mm) actuelle
             global x 
             app.XSlider.Value = x(1);
             app.mmEditField.Value = x(1);
             app.YSlider.Value = x(2);
             app.mmEditField_2.Value = x(2);
             app.ZSlider.Value = x(3);
             app.mmEditField_3.Value = x(3);
             app.RoulisSlider.Value = x(4);
             app.EditField_10.Value = 180/pi*x(4);
             app.TangageSlider.Value = x(5);
             app.EditField_11.Value = 180/pi*x(5);
             app.LacetSlider.Value = x(6);
             app.EditField_12.Value = 180/pi*x(6);
        end
        
        function [] = send_p(app)
            % Envoie de la commande de la position articulaire q
            % en convertissante q (en rad) vers p en (ticks)
             global q systemState enabled_ddl
             if strcmp(systemState,'safe')
                 app.p(1) = 2048000/pi*q(1);
                 app.p(2) = -2048000/pi*(q(2)-pi/2);
                 app.p(3) = 1280000/pi*q(3);
                 app.p(4) = -1280000/pi*q(4);
                 %app.p(5) = k5*q(5);
                 %app.p(6) = k6*q(6);
                 app.p(5) = NaN;
                 app.p(6) = NaN;
                 set_p(app.p);
                 printToTerminal(app, "Position Envoyée.");
             else
                 printToTerminal(app, "Assurer que le système soit initialisé et sécurisé avant d'envoyer une commande.");
                 printToTerminal(app, "Si le problème persiste, fermer l'application et débrancher le robot pour environ 10 secondes et reconnecter.");
             end
             
        end
        
        function [] = CineDirect(app)
            % Cinématique directe du robot : détermination de la position
            % cartésienne x (en mm) en fonction de la position articulaire
            % q (en rad)
             global q
             global x
             global RobotC
             NOAP = RobotC.fkine(q);
             x(1:3) = NOAP.t;
             rpy = NOAP.torpy();
             x(4:6) = rpy(([3 2 1]));
        end
        
        function [] = update_qx(app)
            % Fonction effectuant les mises à jours combinées de
            % l'affichage de l'application et de l'envoie de commande
            global q
            global RobotC
            update_q(app);
            send_p(app);
            CineDirect(app);
            update_x(app);
            plotCrisp(app);
            % RobotC.plot(q);
        end
        
        function [] = CineInverse(app)
            % Cinématique inverse itérative du robot : détermination de la
            % position articulaire q (en rad) en fonction de la postion
            % catésienne x (en mm)
             global q x a7 d7
             global RobotC06
             % Conversion des angles Roll-Pitch-Yaw et position en matrice
             % spatiale NOAP dans le format SE3
             NOAP = SE3.rpy(x(6),x(5),x(4));
             NOAP.t = x(1:3);
             NOAP.t = NOAP.t - d7*NOAP.a - a7*NOAP.n;
             q = RobotC06.ikcon(NOAP,q(1:6));
             % à Modifier pour position seulement       
        end
        
        function [] = TimerCallbackFunction(app)
            % Timer qui effectue une mise à jour de paramètres des
            % contrôleurs du système.
            global systemState
            if ~strcmp(systemState,"safe")
                updateParamData(app);
            end
        end
        
        function []=RecordTimerCallbackFunction(app)
            % Enregistement des données du robot.
            global FileID_RecordTimer
            global ToRecord
            global ToRecordMap
            global temps_init
            global firstloopDone
            
            a=[];
            v=[];
            pos=[];
                       
            for i=1:size(ToRecord,2)
                
                if ToRecord(i)=="On"

                    switch ToRecordMap(i)
                        
                        case "Acceleration"
                            [ a ] = get_a();
%                             fprintf(FileID_RecordTimer,'Acceleration : %s \n',a);
                        case "Vitesse"
                            [ v ] = get_v();
%                             fprintf(FileID_RecordTimer,'Vitesse : %s \n',v);
                        case "Position"
                            [ pos ] = get_p();
%                             fprintf(FileID_RecordTimer,'Position : %s \n',pos);
                    end
                end
                
            end
            fprintf(FileID_RecordTimer,'Temps[sec] \t');
            fprintf(FileID_RecordTimer,'%s \t',ToRecordMap(ToRecord=="On"));
            fprintf(FileID_RecordTimer,'$$\n');
            dataToRecord=[pos', v', a'];
            % Optimisable...
            temps_ecoule=num2str((now-temps_init)*3600*25);
            str=[num2str(temps_ecoule) ' \t'];
            for i=1:size(dataToRecord,2)
               str=[str '%1.3f \t'];
            end
            
            fprintf(FileID_RecordTimer,[str '$$\n'],dataToRecord');
            
            %Affichage dans le graphe en RealTime
                    global ToPrintDDL %On et Off de tous les DDls
                    global ToRecordGraph %Contient les noms des variables a plot
                    global ToPrintDDLMap %Trouve les lignes à updater, les n dernieres lignes de l'array d'objet sont les legendes (liste dans l'ordre qu'ils sont appelés dans RealTimeGraph)
                    global timevect %On ramene tous le vecteur et pas juste les dimensions parcequ'on va l'utiliser sans le recréer quand on repasse a tous les callbacks de ce timer
                    global dataMatrix %On sort cette matricee parce que la fonction de la boit de dialogue a permise de savoir le user veut combien de données sur les graphes en temps réel, [nDDl x nVariablesAPrint x DonneesTempsMax]
                    global compteur
                                       
                        if compteur<=size(dataMatrix,3)
                            compteur=compteur+1; %Incrementation du compteur
                        else
                            compteur=0;
                        end
                    colonnesVariablesVoulues=zeros(1,size(dataMatrix,2));
                    col_index=0;
                for m=1:size(ToRecordGraph,2)
                    tester=strcmp(ToRecordGraph(m),ToRecordMap);
                    if any(tester)
                    col_index=col_index+1;    
                    colonnesVariablesVoulues(col_index)=find(tester);
                    end
                end
           if compteur==0 % Verifie si on a rempli une fois dataMatrix, si oui on ajoute la data qu'au debut avec un decalement
               dataMatrix=circshift(dataMatrix,-1,3);
               dataMatrix(:,:,1)= dataToRecord(strcmp(ToPrintDDL,"On"),colonnesVariablesVoulues);     
               firstloopDone=1;
           elseif firstloopDone==0
            dataMatrix(:,:,compteur)= dataToRecord(strcmp(ToPrintDDL,"On"),colonnesVariablesVoulues);     
           end
            
            if ishandle(99) %Vérifie que user a bien créé la figure 99 qui correspond au RealTimeGraph, sinon fait pas de plot
                        figure(99)
                        
                        % Pour l'instant Matlab ne permet pas de faire des
                        % graphes en joutant des points à mesure si on veut
                        % garder les lignes brisées, c'est donc le même
                        % principe de code que dans la boite de dialogue
                        % pour crééer les graphes
                        
                        % Ainsi on clear all graphes sur figure(99) :
                        clf('reset');
                    for i= 1:size(ToRecordGraph,2)
                       subplot(size(ToRecordGraph,2)+1,1,i) % le dernier subplot aura la legende
                            
                       hold on
                       for k=1:sum(strcmp(ToPrintDDL,"On"))

                         plot(timevect,squeeze(dataMatrix(k,colonnesVariablesVoulues(i),:)));

                       end
                       hold off
                       title(ToRecordGraph(i))

                    end
                        
                    % Ajout de la legende pour tous les graphes!
                        subplot(size(ToRecordGraph,2)+1,1,size(ToRecordGraph,2)+1)
                        % En affichant (0,0) et en detruisant le plot on
                        % fait une legende pour tous les graphes
                        
                        hold on
                        for i=1:size(dataMatrix,1)
                            plot(squeeze(dataMatrix(i,1,1)),squeeze(dataMatrix(i,1,1)))
                        end
                        hold off
                        axis off
                        legend(ToPrintDDLMap(strcmp(ToPrintDDL,"On")))
                
            end
            
        end
        
        function [ ] = init_communications(app)  
            % Appel du protocol d'initialisation des communications
            % Initialisation du fichier de mémoire
            printToTerminal(app, "Initialisation des communications...");
            init_commfile();
            printToTerminal(app, "Appuyer sur le bouton Démarrage lorsque la fenêtre CommBot.m est exécutée.");
        end
        
        function [ ] = printToTerminal(app, string)
            % fonction d'écriture des messages dans le terminal de
            % l'application
            app.Terminal.Items{length(app.Terminal.Items)+1} = sprintf('%s', string);        
            app.Terminal.scroll('bottom');
        end
        
        function [] = update_TextApprentissage(app)
            % Mise à jour de la boîte d'indication de la trajectoire en cours
            % d'enregistement. 
            global trajectoire timeTrajectoire nomTrajectoire
            global RobotC q
            if ~isempty(trajectoire)
                plotCrisp(app);
                app.ApprentissagePos.Value = '';
                app.LabelTrajectory.Text = nomTrajectoire;
                if(size(trajectoire,1) ~= 0)
                    app.ApprentissagePos.Value{1} = sprintf('Temps entre les positions : %3.2f seconde(s)', timeTrajectoire);
                    app.ApprentissagePos.Value{2} = sprintf('%s', "                           X              Y               Z");
                    for i = 1:size(trajectoire,1)
                        app.ApprentissagePos.Value{i+2} = sprintf('Position %i: \t %5.1f \t %5.1f \t %5.1f',i, trajectoire(i,8),trajectoire(i,9),trajectoire(i,10));
                    end
                end
            else
                app.ApprentissagePos.Value = '';
            end
        end
        
        function updateSystemState(app)
            % Détermination de l'état du système
            global systemState enabled_ddl initState demarrageState q

            testStateVmax = enabled_ddl(1:6) == ((app.Vmax > 0) & (app.Vmax < 1500)); 
            if sum(testStateVmax) == 6 && (sum(enabled_ddl(1:6)) > 0)
               app.VitesseMaximaleEditField.BackgroundColor = [0.39,0.83,0.07];
            else
               app.VitesseMaximaleEditField.BackgroundColor = [1.00,0.25,0.25];
            end
            
            testStateIdemar = enabled_ddl(1:6) == ((app.Idemar > 1000) & (app.Idemar < 8000));
            if sum(testStateIdemar) == 6 && (sum(enabled_ddl(1:6)) > 0)
               app.CourantdedmarEditField.BackgroundColor = [0.39,0.83,0.07];
            else
               app.CourantdedmarEditField.BackgroundColor = [1.00,0.25,0.25];
            end
            
            testStateCoupleP = enabled_ddl(1:6) == ((app.CoupleP > 1) & (app.CoupleP < 1000));
            if sum(testStateCoupleP) == 6 && (sum(enabled_ddl(1:6)) > 0)
               app.CouplePEditField.BackgroundColor = [0.39,0.83,0.07];
            else
               app.CouplePEditField.BackgroundColor = [1.00,0.25,0.25];
            end
            
            testStateCoupleI = enabled_ddl(1:6) == ((app.CoupleI > 1) & (app.CoupleI < 1000));
            if sum(testStateCoupleI) == 6 && (sum(enabled_ddl(1:6)) > 0)
               app.CoupleIEditField.BackgroundColor = [0.39,0.83,0.07];
            else
               app.CoupleIEditField.BackgroundColor = [1.00,0.25,0.25];
            end
            
            testStateVitesseP = enabled_ddl(1:6) == ((app.VitesseP > 1) & (app.VitesseP < 6000));
            if sum(testStateVitesseP) == 6 && (sum(enabled_ddl(1:6)) > 0)
               app.VitessePEditField.BackgroundColor = [0.39,0.83,0.07];
            else
               app.VitessePEditField.BackgroundColor = [1.00,0.25,0.25];
            end
            
            testStateVitesseI = enabled_ddl(1:6) == ((app.VitesseI > 1) & (app.VitesseI < 1000));
            if sum(testStateVitesseI) == 6 && (sum(enabled_ddl(1:6)) > 0)
               app.VitesseIEditField.BackgroundColor = [0.39,0.83,0.07];
            else
               app.VitesseIEditField.BackgroundColor = [1.00,0.25,0.25];
            end
            
            testStatePositionP = enabled_ddl(1:6) == ((app.PositionP > 1) & (app.PositionP < 1000));
            if sum(testStatePositionP) == 6 && (sum(enabled_ddl(1:6)) > 0)
               app.PositionPEditField.BackgroundColor = [0.39,0.83,0.07];
            else
               app.PositionPEditField.BackgroundColor = [1.00,0.25,0.25];
            end
            
            testSumParametres = sum(testStateVmax & testStateIdemar...
                & testStateCoupleP & testStateCoupleI...
                & testStateVitesseP & testStateVitesseI...
                & testStatePositionP); % a ajouter autres parametres
            if ( testSumParametres == 6) && (sum(enabled_ddl(1:6)) > 0) && initState && demarrageState
                systemState = 'safe';
                q = [0 pi/2 0 0 0 0 0];
                update_qx(app)
            else 
                systemState = 'dangerous';
            end
            if strcmp(systemState,'safe')
                app.EtatDEL.Color = [0.39,0.83,0.07];
            else
                app.EtatDEL.Color = [1.00,0.25,0.25];
            end
        end
        
        function [] = updateParamSelectionDDL(app)
        global enabled_ddl
            app.ParamSelectionDDL.Items = {}; 
            n = 0;
            if enabled_ddl(1)
                n = n + 1;
                app.ParamSelectionDDL.Items{n} = 'Pectoral (1)';
            end
            if enabled_ddl(2)
                n = n + 1;
                app.ParamSelectionDDL.Items{n} = 'Épaule (2)';
            end
            if enabled_ddl(3)
                n = n + 1;
                app.ParamSelectionDDL.Items{n} = 'Bras (3)';
            end
            if enabled_ddl(4)
                n = n + 1;
                app.ParamSelectionDDL.Items{n} = 'Coude (4)';
            end
            if enabled_ddl(5)
                n = n + 1;
                app.ParamSelectionDDL.Items{n} = 'Avant-bras (5)';
            end
            if enabled_ddl(6)
                n = n + 1;
                app.ParamSelectionDDL.Items{n} = 'Poignet (6)';
            end
            if n == 0
                app.ParamSelectionDDL.Items{1} = 'Aucun DDL activé';
            end
            updateParamData(app);
        end
        
        function [] = updateParamData(app)
          % Mise à joue de l'affichage des paramètres du robot en fonction
          % des degrés de libertés activés
         global ddl_active
         ddl_active = 0;
            if strcmp(app.ParamSelectionDDL.Value,'Pectoral (1)')
                ddl_active = 1;
            end
            if strcmp(app.ParamSelectionDDL.Value,'Épaule (2)')
                ddl_active = 2;
            end
            if strcmp(app.ParamSelectionDDL.Value,'Bras (3)')
                ddl_active = 3;
            end
            if strcmp(app.ParamSelectionDDL.Value,'Coude (4)')
                ddl_active = 4;
            end
            if strcmp(app.ParamSelectionDDL.Value,'Avant-bras (5)')
                ddl_active = 5;
            end
            if strcmp(app.ParamSelectionDDL.Value,'Poignet (6)')
                ddl_active = 6;
            end
            if ddl_active > 0                  
                app.Idemar = get_Idemar();
                if ~isnan(app.Idemar(ddl_active))
                    app.CourantdedmarEditField.Value = app.Idemar(ddl_active);
                else
                    app.CourantdedmarEditField.Value = 0;
                end
                
                app.Vmax = get_Vmax();
                if ~isnan(app.Vmax(ddl_active))
                    app.VitesseMaximaleEditField.Value = app.Vmax(ddl_active);
                else
                    app.VitesseMaximaleEditField.Value = 0;
                end 
                
                app.CoupleP = get_CoupleP();
                if ~isnan(app.CoupleP(ddl_active))
                    app.CouplePEditField.Value = app.CoupleP(ddl_active);
                else
                    app.CouplePEditField.Value = 0;
                end 
                
                app.CoupleI = get_CoupleI();
                if ~isnan(app.CoupleI(ddl_active))
                    app.CoupleIEditField.Value = app.CoupleI(ddl_active);
                else
                    app.CoupleIEditField.Value = 0;
                end 
                
                app.VitesseP = get_VitesseP();
                if ~isnan(app.VitesseP(ddl_active))
                    app.VitessePEditField.Value = app.VitesseP(ddl_active);
                else
                    app.VitessePEditField.Value = 0;
                end 
                
                app.VitesseI = get_VitesseI();
                if ~isnan(app.VitesseI(ddl_active))
                    app.VitesseIEditField.Value = app.VitesseI(ddl_active);
                else
                    app.VitesseIEditField.Value = 0;
                end 
                
                app.PositionP = get_PositionP();
                if ~isnan(app.PositionP(ddl_active))
                    app.PositionPEditField.Value = app.PositionP(ddl_active);
                else
                    app.PositionPEditField.Value = 0;
                end 
               
            else
                app.VitesseMaximaleEditField.Value = 0;
                app.CourantdedmarEditField.Value = 0;
                app.CouplePEditField.Value = 0;
                app.CoupleIEditField.Value = 0;
                app.VitessePEditField.Value = 0;
                app.VitesseIEditField.Value = 0;
                app.PositionPEditField.Value = 0;
            end
            updateSystemState(app);
        end
    
    function [ ] = init_moteurs(app)
       % Initialisation du programme interne des contrôleurs des moteurs
        global enabled_ddl
        printToTerminal(app, "Initialisation des moteurs");
        stopApplication();
        pause(3);
        startApplication();
        printToTerminal(app, "Fin de l'initialisation des moteurs.");
    end
    
    
     function [ ] = udapteParametresControleurs(app)
        % Enregistrement des nouveaux paramètres des contrôleurs du moteur
        % Cette fonction demande un téléchargement complet du programme des
        % contrôleurs.
        global ParametresControleurs map enabled_ddl
        
        for i = 1:6
            if enabled_ddl(i)
                ParametresControleurs(i,1) = app.Idemar(i);
                ParametresControleurs(i,2) = app.Vmax(i);
                ParametresControleurs(i,3) = app.CoupleP(i);
                ParametresControleurs(i,4) = app.CoupleI(i);
                ParametresControleurs(i,5) = app.VitesseP(i);
                ParametresControleurs(i,6) = app.VitesseI(i);
                ParametresControleurs(i,7) = app.PositionP(i);
            end
        end
        
        fileID = fopen('ParametresControleurs.bin','w');
        fwrite(fileID, ParametresControleurs,'uint32');
        fclose(fileID);
        
        motorID = uint8(0);
        DATA = []; Datagrams = [];
        fileID = fopen('./TMCMBinaryFiles/MainProgramCRISP_V3.bin');
        DATA = fread(fileID);
        fclose(fileID);
        NumberOfLine = size(DATA,1)/8 + 3;
        Datagrams = uint8(zeros(NumberOfLine,9));
        
        fileID2 = fopen('ParametresControleurs.bin','r');
        MatrixRead= fread(fileID2, [6, 7], 'uint32');
        fclose(fileID2);
        
        
        decalage = 0;
        for motorID = 1:6
            if enabled_ddl(motorID)
                % Enter DownloadMode
                Datagrams(1+(NumberOfLine*decalage),1) = motorID+1; 
                Datagrams(1+(NumberOfLine*decalage),2) = 132;
                Datagrams(1+(NumberOfLine*decalage),9) = uint8(sum(Datagrams(1+(NumberOfLine*decalage),[1:8]))); % checksum should not overflow
                % Loading of commands from DATA
                for i = 1 : NumberOfLine - 3
                    Datagrams(i+1+(NumberOfLine*decalage),1) = motorID+1;
                    Datagram = [motorID+1; DATA([(i-1)*8+1:(i-1)*8+7]); 0];
                    if Datagram(2) == 5 % Commande SAP
                        switch Datagram(3) 
                            case 177 % Start Current
                                Datagram = create_dg(motorID+1, 5, 177, 0, MatrixRead(motorID, 1));
                            case 4  % Max Velocity
                                Datagram = create_dg(motorID+1, 5, 4, 0, MatrixRead(motorID, 2));
                            case 172 % CoupleP
                                Datagram = create_dg(motorID+1, 5, 172, 0, MatrixRead(motorID, 3));
                            case 173 % CoupleI
                                Datagram = create_dg(motorID+1, 5, 173, 0, MatrixRead(motorID, 4));
                            case 234 % VelocityP
                                Datagram = create_dg(motorID+1, 5, 234, 0, MatrixRead(motorID, 5));
                            case 235 % VelocityI
                                Datagram = create_dg(motorID+1, 5, 235, 0, MatrixRead(motorID, 6));
                            case 230 % PositionP
                                Datagram = create_dg(motorID+1, 5, 230, 0, MatrixRead(motorID, 7));
                        end
                    end
                    Datagrams(i+1+(NumberOfLine*decalage),[1:8]) = Datagram(1:8);
                    Datagrams(i+1+(NumberOfLine*decalage),9) = bitand(sum(Datagrams(i+1+(NumberOfLine*decalage),[1:8])),uint32(255)); % Checksum with overflow (prevent saturation)
                end
                % Opcode 0 send to the module 
                Datagrams(NumberOfLine - 1 +(NumberOfLine*decalage),1) = motorID+1; 
                Datagrams(NumberOfLine - 1 + (NumberOfLine*decalage),9) = sum(Datagrams(NumberOfLine - 1 + (NumberOfLine*decalage),[1:8])); % checksum should not overflow
                % Quit download Mode
                Datagrams(NumberOfLine + (NumberOfLine*decalage),1) =  motorID+1; 
                Datagrams(NumberOfLine + (NumberOfLine*decalage),2) = 133;
                Datagrams(NumberOfLine + (NumberOfLine*decalage),9) = sum(Datagrams(NumberOfLine + (NumberOfLine*decalage),[1:8])); % checksum should not overflow
                decalage = decalage +1 ;
            end
        end
        stopApplication();
        pause(2);
        for i = 1 : size(Datagrams,1)
             send_other(Datagrams(i,:));
             if mod(i,size(Datagrams,1)/4) == 0
                pause(1);
             end
        end
        printToTerminal(app, "Les paramètres des contrôleurs a bien été initialisé.")
     end
    
        function [] = plotCrisp(app, point)
         % Affichage du modèle 3D du robot Crisp avec fichiers STL en
         % fonctions de la position articulaire q (en mm) ou de la
         % trajectoire en utilisation de l'application
         global q trajectoire
         clf;
         if nargin > 1
             q_point = point;
         else
             q_point = q;
         end
         T2 = ROTZ(q_point(1)-pi/2)'*TRANSZ(187.8)';
         T3 = ROTZ(-q_point(2))'*ROTY(-pi/2)'*TRANSZ(79.32)'*TRANSY(52.62)';
         T4 = ROTZ(q_point(3))'*ROTY(pi/2)'*TRANSY(5)'*TRANSX(110)'*ROTY(pi)';
         T5 = ROTZ(q_point(4))'*ROTY(-pi/2)'*TRANSY(10)'*TRANSZ(260)';
         T6 = ROTZ(q_point(5))'*ROTY(pi/2)'*TRANSY(-20)'*TRANSX(80)';
         T7 = ROTZ(q_point(6))'*ROTY(-pi/2)'*TRANSX(0)'*TRANSZ(226)';
         Vplot = [app.V(1:app.S(1),:);app.V(app.S(1)+1:app.S(1)+app.S(2),:)*T2];
         Vplot = [Vplot; app.V(app.S(1)+app.S(2)+1:app.S(1)+app.S(2)+app.S(3),:)*T3*T2];
         Vplot = [Vplot; app.V(app.S(1)+app.S(2)+app.S(3)+1:app.S(1)+app.S(2)+app.S(3)+app.S(4),:)*T4*T3*T2];
         Vplot = [Vplot; app.V(app.S(1)+app.S(2)+app.S(3)+app.S(4)+1:app.S(1)+app.S(2)+app.S(3)+app.S(4)+app.S(5),:)*T5*T4*T3*T2];
         Vplot = [Vplot; app.V(app.S(1)+app.S(2)+app.S(3)+app.S(4)+app.S(5)+1:app.S(1)+app.S(2)+app.S(3)+app.S(4)+app.S(5)+app.S(6),:)*T6*T5*T4*T3*T2];
         Vplot = [Vplot; app.V(app.S(1)+app.S(2)+app.S(3)+app.S(4)+app.S(5)+app.S(6)+1:app.S(1)+app.S(2)+app.S(3)+app.S(4)+app.S(5)+app.S(6)+app.S(7),:)*T7*T6*T5*T4*T3*T2];
         TR = triangulation(app.F,Vplot(:,1:3));
         trisurf(TR,'edgecolor', 'none', 'facecolor', [0.5 0.5 0.5]);
         xlim([-800 1200])
         ylim([-1000 1000])
         zlim([-600 1400])
         light('Position',[-600 500 500],'Style','local')
         light('Position',[-500 -500 -500],'Style','local')
         light('Position',[1000 1000 1000],'Style','local')
         view([-1 -1.5 1])
         if ~isempty(trajectoire)
             hold on
             plotp(trajectoire(:,[8,9,10])','-bx','LineWidth',2)
             hold off
         end
    end 

end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Fonction d'initialisation: Cette fonction est appellées lors
            % de l'ouverture de l'application
            app.NomdufichierEditField.Value="DonneesEnregistrees";    
            app.DossierEditField.Value=pwd;
                % Initialisation des degrés de liberté du robot Crisp
                global enabled_ddl
                enabled_ddl = [0, 0, 0, 0, 0, 0, 0];
                
                global ddl_active
                ddl_active = 0;
                
                % Initialisation du fichier de communications
                global map 
                map = get_memmapfile();  
                empty = uint8(zeros(1,9));
                fields = fieldnames(map.data(1));
                n = length(fields);
                for i = 1:n
                    field = fields(i);
                    write_dg(map, field, empty, 1);
                end
                
                global ParametresControleurs
                fileID = fopen('./TMCMBinaryFiles/ParametresControleurs.bin','r');
                ParametresControleurs = fread(fileID, [6, 7], 'uint32');
                fclose(fileID);
                
                global initState 
                initState = 0;
                
                global demarrageState
                demarrageState = 0;
                
                global trajectoire
                trajectoire = [];
                
                global commandTrajectory
                commandTrajectory = [];
                
                global nomTrajectoire 
                nomTrajectoire = 'Trajectoire_Default_Name';
                app.LabelTrajectory.Text = nomTrajectoire;
                
                global timeTrajectoire
                timeTrajectoire = 2;
                
                global commandTimeTrajectory
                commandTimeTrajectory = [];

                global loopingMode % Mode trajectoire en boucle acitivé : 10 = vrai; 0 = faux.
                if strcmp(app.LoopingSwitch.Value, "En boucle")
                    loopingMode = 10;
                else 
                    loopingMode = 0;
                end
                
                d = dir('.\Trajectoires\*.dat');
                names = {d.name};
                if size(names) ~=0
                   app.TrajectoireList.Items = names;
                end
                
                global systemState
                systemState = 'dangerous';
                updateSystemState(app);
                updateParamSelectionDDL(app);
                
                printToTerminal(app, "Bienvenue sur l'application CRISP!");
                
                %Initialisation du timer
                app.myTimer = timer('Period',2,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
                app.myTimer.TimerFcn = @(obj, event)TimerCallbackFunction(app);
                start(app.myTimer);
                
                app.RecordTimer = timer('Period',0.5,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
                app.RecordTimer.TimerFcn = @(obj, event)RecordTimerCallbackFunction(app);
                 %start(app.RecordTimer); % Activé par un bouton ailleurs dans l'appli!
              
                
                disp('Robotics, Vision & Control: (c) Peter Corke 1992-2017 http://www.petercorke.com')
                
                if verLessThan('matlab', '7.0')
                    warning('You are running a very old (and unsupported) version of MATLAB.  You will very likely encounter significant problems using the toolboxes but you are on your own with this');
                end
                tb = false;
                rvcpath = fileparts( mfilename('fullpath') );
                
                robotpath = fullfile(rvcpath, 'robot');
                if exist(robotpath,'dir')
                    addpath(robotpath);
                    tb = true;
                    if exist('startup_rtb') == 2
                        startup_rtb
                    end
                end
                
                visionpath = fullfile(rvcpath, 'vision');
                if exist(visionpath,'dir')
                    addpath(visionpath);
                    tb = true;
                    if exist('startup_mvtb') == 2
                        startup_mvtb
                    end
                end
                
                if tb
                    addpath(fullfile(rvcpath, 'common'));
                    addpath(fullfile(rvcpath, 'simulink'));
                end
                
                clear tb rvcpath robotpath visionpath
                
                % Initialisation du port de communication sérial par défault
                set_Port('COM14');
                
                % Initialisation de la géométrie du robot Crisp
                global d7 a7
                d7 = 216.15;
                app.d7mmEditField.Value = d7;
                a7 = 0;
                app.a7mmEditField.Value = a7;
                global RobotC; 
                % Définition des paramètres de denavit-Hartenberg du robot
                RobotC=SerialLink([0, 257.1, 78.2,  pi/2,  0,   0;...
                                   0,      0,    0,  pi/2,  0,   0;...
                                   0,  395.2, 18.1, -pi/2,  0,   0;...
                                   0,     0, -18.1,  pi/2,  0,   0;...
                                   0, 262.6,      0,-pi/2,  0,   0;...
                                   0,     0,      0, pi/2,  0,   0;...
                                   0     d7,     a7,    0,  0,   0],'name', ' '); 
                               
                RobotC.links(1).qlim = [-pi/2 pi/2];
                RobotC.links(2).qlim = [pi/4 pi/2+pi/4];
                RobotC.links(3).qlim = [-pi pi];
                RobotC.links(4).qlim = [-pi/2 pi/2];
                RobotC.links(5).qlim = [-pi pi];
                RobotC.links(6).qlim = [-pi/2 pi/2];
                global fig
                fig = figure('Name','Robot CRISP','NumberTitle','off');
                % Retrait du dernier de la distance de la main
                % Utilisé pour la cinématique inverse itérative
                global RobotC06
                RobotC06 = SerialLink(RobotC.links(1:6));
                
                % Initialisation de la fenêtre du plot du Robot Crisp
                hold on
                grid on
                view(3) 
                axis([-800,1500,-1400,1500,-800,1000])
                view([180 -270 180])
                global q
                q=[0, pi/2, 0, 0, 0, 0, 0];
                update_q(app);
                CineDirect(app);
                update_x(app);
                               
                %% Initialisation
                % Download de la base du pectoral
                [V1, F1, n1, name1] = stlread('./.STL/M10-ASS-101A-00.STL');
                v1=V1.Points;
                f1=V1.ConnectivityList;
                v1 = [v1, ones(size(v1,1),1)];
                v1 = v1*TRANSY(-151.52)'*TRANSX(-132.8)'*ROTX(pi)'*TRANSZ(320.8)'*ROTZ(pi/2)';
                app.V = [app.V;v1]; % avec derniere ligne de 1
                app.F = [app.F;f1];
                app.S = [app.S; size(v1,1)];
                
                % Download de l'epaule
                [V2, F2, n2, name2] = stlread('./.STL/M10-ASS-202_REV05.STL');
                v2=V2.Points;
                f2=V2.ConnectivityList;
                v2 = [v2, ones(size(v2,1),1)];
                v2 = v2*TRANSY(-75.406)'*TRANSX(-126.14)'*TRANSZ(-172.32)'*ROTX(pi)'*ROTZ(-pi/2)';
                app.V = [app.V;v2];  % avec derniere ligne de 1
                app.F = [app.F;f2+app.S(1)];
                app.S = [app.S; size(v2,1)];

                % Download du lien de l'épaule
                [V3, F3, n3, name3] = stlread('./.STL/M10-ASS-501.STL');
                v3=V3.Points;
                f3=V3.ConnectivityList;
                v3 = [v3, ones(size(v3,1),1)];
                v3 = v3*TRANSX(-77.144)'*TRANSZ(-162.32)'*TRANSY(-82.04)'*ROTY(pi/2)';%'*ROTX(pi)'*ROTZ(pi/2)';
                app.V = [app.V;v3];  % avec derniere ligne de 1
                app.F = [app.F;f3+app.S(1)+app.S(2)];
                app.S = [app.S; size(v3,1)];

                %% Download du biceps
                v4 = []; f4 = []; S4 = [];
               %[V40, F40, n40, name40] = stlread('./.STL/A-01-03_1[coque].STL');
                [V40, F40, n40, name40] = stlread('./.STL/CoqueBicepsSplit.STL');
                v40=V40.Points;
                f40=V40.ConnectivityList;
                v40 = [v40, ones(size(v40,1),1)];
                v40 = v40*TRANSZ(-120)'*TRANSY(-60)'*ROTY(-pi/2)';
                v4 = [v4;v40];  % avec derniere ligne de 1
                f4 = [f4;f40];
                S4 = [S4; size(v40,1)];
                app.V = [app.V;v4]; 
                app.F = [app.F;f4+app.S(1)+app.S(2)+app.S(3)];
                app.S = [app.S; size(v4,1)];
                
                %% Download du module du coude
                [V5, F5, n5, name5] = stlread('./.STL/M10-ASS-504_COUDE.STL');
                v5=V5.Points;
                f5=V5.ConnectivityList;
                v5 = [v5, ones(size(v5,1),1)];
                v5 = v5*ROTY(-pi/2)'*TRANSZ(-88.1423)'*TRANSX(158.2)'*TRANSY(-80.425)'*ROTX(pi)';
                % Coque Coude
                [V51, F51, n51, name51] = stlread('./.STL/Coude.STL');
                v51=V51.Points;
                f51=V51.ConnectivityList;
                v51 = [v51, ones(size(v51,1),1)];
                v51 = v51*ROTY(0)'*TRANSZ(-97)'*TRANSX(-48)'*TRANSY(-42.425)'*ROTX(pi)';
                f5 = [f5;f51+size(v5,1)];
                v5 = [v5;v51]; 
                app.V = [app.V;v5];  % avec derniere ligne de 1
                app.F = [app.F;f5+app.S(1)+app.S(2)+app.S(3)+app.S(4)];
                app.S = [app.S; size(v5,1)];

                v6 = []; f6 = []; S6 = [];
%                 [V60, F60, n60, name60] = stlread('./.STL/A-01-04[av.bras] - avant_bras_droit-1.STL');
                [V60, F60, n60, name60] = stlread('./.STL/A-01-04_17[coque test av-bras].STL');
                v60=V60.Points;
                f60=V60.ConnectivityList;
                v60 =[v60, ones(size(v60,1),1)];
                v60 = v60*ROTX(pi)'*TRANSZ(285)'*TRANSY(84.4-30)'*TRANSX(-74.5)';
                v6 = [v6;v60];  % avec derniere ligne de 1
                f6 = [f6;f60];
                S6 = [S6; size(v60,1)];
                app.V = [app.V;v6];  % avec derniere ligne de 1
                app.F = [app.F;f6+app.S(1)+app.S(2)+app.S(3)+app.S(4)+app.S(5)];
                app.S = [app.S; size(v6,1)];
                
                %% Download du poignet
                v7 = []; f7 = []; S7 = [];
                [V70, F70, n70, name70] = stlread('./.STL/A-01-04[av.bras] - A-01-04_01[plate]-1.STL');
                v70=V70.Points;
                f70=V70.ConnectivityList;
                v70 =[v70, ones(size(v70,1),1)];
                v7 = [v7;v70];  % avec derniere ligne de 1
                v7 = v7*ROTX(pi/2)'*TRANSY(93.53)'*TRANSX(-231.35)'*TRANSZ(-112.3)'*ROTZ(-0.2415)*ROTX(-0.022195);
                f7 = [f7;f70];
                S7 = size(v70,1);
                
                % Download coque poignet 
                [V71, F71, n71, name71] = stlread('./.STL/A-01-04_16[coque test2].STL');
                v71=V71.Points;
                f71=V71.ConnectivityList;
                v71 = [v71, ones(size(v71,1),1)];
                v71 = v71*ROTY(-0.23)'*ROTX(-0.015)'*TRANSY(70)'*TRANSX(225)'*TRANSZ(48)'*ROTX(pi/2)'*TRANSY(93.53)'*TRANSX(-231.35)'*TRANSZ(-112.3)'*ROTZ(-0.2415)*ROTX(-0.022195)*ROTZ(pi/2)'*ROTX(-pi/2)'*TRANSZ(26)'*TRANSY(-1)';
                v7 = [v7;v71]; 
                v7 = v7*ROTY(pi)'*ROTZ(pi)';
                f7 = [f7;f71+S7(1)];%
                S7 = S7 + size(v71,1);
                
                % Pince (Temporaire)
                [V72, F72, n72, name72] = stlread('./.STL/SCHUNK_0303066_KGG_100_80__011.STL');
                v72=V72.Points;
                f72=V72.ConnectivityList;
                v72 = [v72, ones(size(v72,1),1)];
                v72 = v72*ROTZ(pi/2)'*TRANSY(-75)'*TRANSX(238)'*TRANSZ(-30)';
                v7 = [v7;v72];
                f7 = [f7;f72+S7];
                S7 = S7 + size(v72,1);
                
                app.V = [app.V;v7];  % avec derniere ligne de 1
                app.F = [app.F;f7+app.S(1)+app.S(2)+app.S(3)+app.S(4)+app.S(5)+app.S(6)];
                app.S = [app.S; S7];
                
                plotCrisp(app);
                hold off
        end

        % Button pushed function: InitialisationButton
        function InitialisationButtonPushed(app, event)
           % Appelle de l'ouverture de la fenêtre Matlab du Commbot pour initialisation le 
           % protocole de communication
            global initState enabled_ddl
                    if sum(enabled_ddl(1:6)) ~= 0
                        % Variable Global d'activation des degrés de liberté
                        PectoralSwitchValueChanged(app, event);
                        EpauleSwitchValueChanged(app, event);
                        BrasSwitchValueChanged(app, event);
                        CoudeSwitchValueChanged(app, event);
                        AvantbrasSwitchValueChanged(app, event);
                        PoignetSwitchValueChanged(app, event);
                        OutilSwitchValueChanged(app, event);
                        updateParamData(app);
                        %Ouverture d'une nouvelle fenêtre Matlab pour l'exécution du programme 
                        % de communication sérial (CommBot.m)
                        selpath = uigetdir("\","Sélectionner l'emplacement de votre programme matlab.exe (bin)" );
                        if selpath ~= 0
                            initState = 1;
                            openMainMatlab = strcat('"',selpath,'\matlab.exe" -r "CommBot"');
                            system(openMainMatlab);
                            %fonction d'initialisation
                            init_communications(app);
                            updateParamData(app);
                        end
                    else
                        printToTerminal(app, "Activer au moins un degré de liberté avant d'effectuer l'initialisation.");
                    end
        end

        % Value changed function: TouslesDDLSwitch
        function TouslesDDLSwitchValueChanged(app, event)
          % Activation de tous les DDLs simultanément
            value = app.TouslesDDLSwitch.Value;
            if value == "On"
                app.PectoralSwitch.Value = "On";
                app.EpauleSwitch.Value = "On";
                app.BrasSwitch.Value = "On";
                app.CoudeSwitch.Value = "On";
                app.AvantbrasSwitch.Value = "On";
                app.PoignetSwitch.Value = "On";
                app.OutilSwitch.Value = "On";
            else 
                app.PectoralSwitch.Value = "Off";
                app.EpauleSwitch.Value = "Off";
                app.BrasSwitch.Value = "Off";
                app.CoudeSwitch.Value = "Off";
                app.AvantbrasSwitch.Value = "Off";
                app.PoignetSwitch.Value = "Off";
                app.OutilSwitch.Value = "Off";
            end
            OutilSwitchValueChanged(app, event)
            PectoralSwitchValueChanged(app, event);
            EpauleSwitchValueChanged(app, event);
            BrasSwitchValueChanged(app, event);
            CoudeSwitchValueChanged(app, event);
            AvantbrasSwitchValueChanged(app, event);
            PoignetSwitchValueChanged(app, event);
        end

        % Value changed function: OutilSwitch
        function OutilSwitchValueChanged(app, event)
            % Activation du DDL de l'outil
            value = app.OutilSwitch.Value;
            global enabled_ddl
            if value == "On"
                enabled_ddl(7) = 1;
            else 
                enabled_ddl(7) = 0;
            end
            updateParamSelectionDDL(app)
        end

        % Value changed function: PectoralSwitch
        function PectoralSwitchValueChanged(app, event)
            % Activation du DDL du pectoral (1er moteur)
            value = app.PectoralSwitch.Value;
            global enabled_ddl
            if value == "On"
                enabled_ddl(1) = 1;
            else 
                enabled_ddl(1) = 0;
                % Must shot them down 1 to 6
            end
            updateParamSelectionDDL(app);
        end

        % Value changed function: EpauleSwitch
        function EpauleSwitchValueChanged(app, event)
            % Activation du DDL de l'épaule (2e moteur)  
            value = app.EpauleSwitch.Value;
            global enabled_ddl
            if value == "On"
                enabled_ddl(2) = 1;
            else 
                enabled_ddl(2) = 0;
            end
            updateParamSelectionDDL(app);
        end

        % Value changed function: BrasSwitch
        function BrasSwitchValueChanged(app, event)
            % Activation du DDL du bras (3e moteur)
            value = app.BrasSwitch.Value;
            global enabled_ddl
            if value == "On"
                enabled_ddl(3) = 1;
            else 
                enabled_ddl(3) = 0;
            end
            updateParamSelectionDDL(app);
        end

        % Value changed function: CoudeSwitch
        function CoudeSwitchValueChanged(app, event)
            % Activation du DDL du coude (4e moteur)
            value = app.CoudeSwitch.Value;
            global enabled_ddl
            if value == "On"
                enabled_ddl(4) = 1;
            else 
                enabled_ddl(4) = 0;
            end  
            updateParamSelectionDDL(app);
        end

        % Value changed function: AvantbrasSwitch
        function AvantbrasSwitchValueChanged(app, event)
            % Activation du DDL de l'avant-bras (5e moteur)
            value = app.AvantbrasSwitch.Value;
            global enabled_ddl
            if value == "On"
                enabled_ddl(5) = 1;
            else 
                enabled_ddl(5) = 0;
            end 
            updateParamSelectionDDL(app);
        end

        % Value changed function: PoignetSwitch
        function PoignetSwitchValueChanged(app, event)
            % Activation du DDL du poignet (6e moteur)
            value = app.PoignetSwitch.Value;
            global enabled_ddl
            if value == "On"
                enabled_ddl(6) = 1;
            else 
                enabled_ddl(6) = 0;
            end 
            updateParamSelectionDDL(app);
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            % Modification du port de communication USB vers R485
            % Voir device manager pour déterminer le bon port
            value = app.PortEditField.Value;
            set_Port(value);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            % Glissement du curseur de la position articulaire du pectoral (1er
            % moteur)
            global q
            global RobotC
            q(1) = event.Value;
            plotCrisp(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            % Modification de la position articulaire du pectoral (1er
            % moteur) 
            global q
            q(1)= app.Slider.Value;
            update_qx(app);
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            % Glissement du curseur de la position articulaire de l'épaule
            % (2e moteur)
            global q
            global RobotC
            q(2) = event.Value;
            plotCrisp(app);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            % Modification de la position articulaire de l'épaule (2e
            % moteur)
            global q
            q(2) = app.Slider_2.Value;
            update_qx(app);
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            % Glissement du curseur de la position articulaire du bras (3e
            % moteur)
            global q
            global RobotC
            q(3) = event.Value;
            plotCrisp(app);
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
             % Modification de la position articulaire du bras (3e
             % moteur)
             global q 
             q(3) = app.Slider_3.Value;
             update_qx(app);
        end

        % Value changing function: Slider_4
        function Slider_4ValueChanging(app, event)
            % Glissement du curseur de la position articulaire du coude (4e
            % moteur)
            global q
            global RobotC
            q(4) = event.Value;
            plotCrisp(app);
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            % Modification de la position articulaire du coude (4e
            % moteur)
            global q
            q(4) = app.Slider_4.Value;
            update_qx(app);
        end

        % Value changing function: Slider_5
        function Slider_5ValueChanging(app, event)
            % Glissement du curseur de la position articulaire de l'avant-bras
            % (5e moteur)
            global q
            global RobotC
            q(5)  = event.Value;
            plotCrisp(app);
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            % Modification de la position articulaire de l'avant bras (5e
            % moteur)
            global q
            q(5) = app.Slider_5.Value;
            update_qx(app);
        end

        % Value changing function: Slider_6
        function Slider_6ValueChanging(app, event)
            % Glissement du curseur de la position articulaire du poignet
            % (6e moteur)
            global q
            global RobotC
            q(6) = event.Value;
            plotCrisp(app);
            %plotCrisp(app);
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            % Modification de la position articulaire du poignet (6e
            % moteur)
            global q
            q(6) = app.Slider_6.Value;
            update_qx(app);
        end

        % Value changed function: MaininactiveButton
        function MaininactiveButtonValueChanged(app, event)
            % Envoie d'une commande (activation et désactivation) à l'outil
            % du robot. Le type d'outil doit être sélectionné dans l'onglet
            % outi
            % format de la commande pour l'outil pneumatique : 9 bytes
            com1=['08', '06', '00', '00', '00', '00', '89', '53', '00']; % Activation pneumatique
            com2=['08', '06', '00', '00', '00', '01', '48', '93', '00']; % Désactivation pneumatique
            com3=['08', '06', '00', '00', '00', '02', '08', '92', '00']; % Activation électrique 
            com4=['08', '06', '00', '00', '00', '03', 'C9', '52', '00']; % Désactivation électrique
            global enabled_ddl systemState
            etatMain = app.MaininactiveButton.Value;
            if(etatMain == 1)
                app.MaininactiveButton.Text = "Main activée";
                app.MaininactiveButton.BackgroundColor = [0.39,0.83,0.07];
            else
                app.MaininactiveButton.Text = "Main inactive";
                app.MaininactiveButton.BackgroundColor = [0.80,0.80,0.80];
                %Enlever activation main
            end
            if strcmp(app.Switch.Value, "Pneumatique")
                if etatMain == 1
                    command = com1;
                else
                    command = com2;
                end
            else % main électrique
                if etatMain == 1
                    command = com3;
                else
                    command = com4;
                end
            end
            if enabled_ddl(7) == 1 && strcmp(systemState, "safe")
                send_other(command);
                create_dg(8, etatMain, typeMain, 0, 0)
                if etatMain == 1
                    printToTerminal(app,strcat("Activation (ouverture) de la main ", app.Switch.Value));
                else
                    printToTerminal(app,strcat("Désactivation (fermeture) de la main ", app.Switch.Value));
                end
            else 
                printToTerminal(app, "Activer d'abord le degré de liberté de la main ou sécuriser le système.")
            end
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            %Changement de la variable x (en mm) dans l'espace cartésien
            %Calcul de la cinématique inverse et mise à jour de l'affichage
            global x
            x(1) = app.XSlider.Value;
            CineInverse(app);
            update_qx(app);
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            %Changement de la variable y (en mm) dans l'espace cartésien
            %Calcul de la cinématique inverse et mise à jour de l'affichage
            global x
            x(2) = app.YSlider.Value;
            CineInverse(app);
            update_qx(app);
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            %Changement de la variable z (en mm) dans l'espace cartésien
            %Calcul de la cinématique inverse et mise à jour de l'affichage
            global x
            x(3) = app.ZSlider.Value;
            CineInverse(app);
            update_qx(app);
        end

        % Value changed function: RoulisSlider
        function RoulisSliderValueChanged(app, event)
            %Changement de la variable du roulis (en °) dans l'espace cartésien
            %Calcul de la cinématique inverse et mise à jour de l'affichage
            global x
            x(4) = app.RoulisSlider.Value;
            CineInverse(app);
            update_qx(app);
        end

        % Value changed function: TangageSlider
        function TangageSliderValueChanged(app, event)
            %Changement de la variable du tanguage (en °) dans l'espace cartésien
            %Calcul de la cinématique inverse et mise à jour de l'affichage
            global x
            x(5) = app.TangageSlider.Value;
            CineInverse(app);
            update_qx(app);
        end

        % Value changed function: LacetSlider
        function LacetSliderValueChanged(app, event)
            %Changement de la variable du lacet (en °) dans l'espace cartésien
            %Calcul de la cinématique inverse et mise à jour de l'affichage
            global x
            x(6) = app.LacetSlider.Value;
            CineInverse(app);
            update_qx(app);
        end

        % Value changed function: d7mmEditField
        function d7mmEditFieldValueChanged(app, event)
            %Modification de la valeur du paramètre d7 selon
            %Denavit-Hartenberg pas l'utilisateur
            global d7 RobotC
            d7 = app.d7mmEditField.Value;
            RobotC.links(7).d = d7;
            update_qx(app);
        end

        % Value changed function: a7mmEditField
        function a7mmEditFieldValueChanged(app, event)
            %Modification de la valeur du paramètre d7 selon
            %Denavit-Hartenberg
            global a7 RobotC
            a7 = app.a7mmEditField.Value;
            RobotC.links(7).a = a7;
            update_qx(app);
        end

        % Button pushed function: PositioninitialeButton
        function PositioninitialeButtonPushed(app, event)
            % Retour à la position initiale (bras à l'horizontal)
            global q 
            q = [0, pi/2, 0, 0, 0, 0, 0];
            update_qx(app);
        end

        % Button pushed function: PositioninitialeButton_2
        function PositioninitialeButton_2Pushed(app, event)
            % Retour à la position initiale (bras à l'horizontal)
            global q 
            q = [0, pi/2, 0, 0, 0, 0, 0];
            update_qx(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            % Changement de la valeur de la rotation articulaire du pectoral
            % à partir de la zone de texte 
            global q
            q(1) = pi/180*app.EditField.Value;
            update_qx(app);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            % Changement de la valeur de la rotation articulaire de
            % l'épaule à partir de la zone de texte 
            global q
            q(2) = pi/180*app.EditField_2.Value;
            update_qx(app);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            % Changement de la valeur de la rotation articulaire du bras
            % à partir de la zone de texte 
            global q
            q(3) = pi/180*app.EditField_3.Value;
            update_qx(app);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            % Changement de la valeur de la rotation articulaire du coude
            % à partir de la zone de texte 
            global q
            q(4) = pi/180*app.EditField_4.Value;
            update_qx(app);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            % Changement de la valeur de la rotation articulaire de l'avant
            %-bras à partir de la zone de texte 
            global q
            q(5) = pi/180*app.EditField_5.Value;
            update_qx(app);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            % Changement de la valeur de la rotation articulaire du poignet
            % à partir de la zone de texte 
            global q
            q(6) = pi/180*app.EditField_6.Value;
            update_qx(app);
        end

        % Value changed function: mmEditField
        function mmEditFieldValueChanged(app, event)
            % Changement de la valeur du déplacement en x (en mm)
            % à partir de la zone de texte 
            global x
            x(1) = app.mmEditField.Value;
            CineInverse(app);
            update_qx(app);
        end

        % Value changed function: mmEditField_2
        function mmEditField_2ValueChanged(app, event)
            % Changement de la valeur du déplacement en y (en mm)
            % à partir de la zone de texte 
            global x
            x(2) = app.mmEditField_2.Value;
            CineInverse(app);
            update_qx(app);
        end

        % Value changed function: mmEditField_3
        function mmEditField_3ValueChanged(app, event)
            % Changement de la valeur du déplacement en z (en mm)
            % à partir de la zone de texte 
            global x
            x(3) = app.mmEditField_3.Value;
            CineInverse(app);
            update_qx(app);
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            % Changement de la valeur du roulis (en °)
            % à partir de la zone de texte 
            global x
            x(4) = pi/180*app.EditField_10.Value;
            CineInverse(app);
            update_qx(app);
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            % Changement de la valeur du tangage (en °)
            % à partir de la zone de texte 
            global x
            x(5) = pi/180*app.EditField_11.Value;
            CineInverse(app);
            update_qx(app);
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            % Changement de la valeur du lacet (en °)
            % à partir de la zone de texte 
            global x
            x(6) = pi/180*app.EditField_12.Value;
            CineInverse(app);
            update_qx(app);
        end

        % Button pushed function: RecPos
        function RecPosButtonPushed(app, event)
            % Ajout de la position actuelle à la liste des positions en
            % mémoire de la trajectoire 
            global trajectoire q x
            trajectoire = [trajectoire; [q, x]];
            update_TextApprentissage(app);
        end

        % Button pushed function: DeletePos
        function DeletePosButtonPushed(app, event)
            % Retrait de la dernière position à la liste des positions en
            % mémoire de la trajectoire 
            global trajectoire
            if size(trajectoire,1) ~= 0
                trajectoire(size(trajectoire,1),:) = [];
            end
            update_TextApprentissage(app);
        end

        % Button pushed function: SetTime
        function SetTimeButtonPushed(app, event)
            % Modification de l'intervalle de temps entre les positions
            global timeTrajectoire
            time{1} = sprintf('%3.3f',timeTrajectoire);
            timeEntered = str2double(inputdlg('Entrer le temps (seconde) entre les positions : ','Time input',1,time));
            if ~isnan(timeEntered)
                timeTrajectoire = timeEntered;
            end
            update_TextApprentissage(app);
        end

        % Button pushed function: Play
        function PlayButtonPushed(app, event)
            % Simulation de la trajectoire avec le modèle 3d (n'envoie pas
            % de commande au robot)
            global trajectoire RobotC timeTrajectoire
            if size(trajectoire,1) ~= 0
                p = trajectoire(1,1:7);
                for i=1:(size(trajectoire,1)-1)
                    p = [p; [linspace(trajectoire(i,1),trajectoire(i+1,1),10)',...
                             linspace(trajectoire(i,2),trajectoire(i+1,2),10)',...
                             linspace(trajectoire(i,3),trajectoire(i+1,3),10)',...
                             linspace(trajectoire(i,4),trajectoire(i+1,4),10)',...
                             linspace(trajectoire(i,5),trajectoire(i+1,5),10)',...
                             linspace(trajectoire(i,6),trajectoire(i+1,6),10)',...
                             zeros(10,1)]];
                end
                for i=1:size(p,1)
                    c = clock;
                    plotCrisp(app,p(i,:));
                    c = clock - c;
                    pause(timeTrajectoire/10 - c(6))
                end
            end
        end

        % Button pushed function: Download
        function DownloadButtonPushed(app, event)
            % Sélection d'une trajectoire enregistrée
            global nomTrajectoire trajectoire timeTrajectoire
            d = dir('.\Trajectoires\*.dat');
            names = {d.name};
            index = listdlg('PromptString',{'Select a file.',...
            'Only one file can be selected at a time.',''},...
            'SelectionMode','single','ListString',names);
            if ~isempty(index)
                nomTrajectoire = names{index}(1:end-4);
                fileName = strcat('Trajectoires\', names{index});
                timeAndTrajectory = dlmread(fileName );
                timeTrajectoire = timeAndTrajectory(1,1);
                trajectoire = timeAndTrajectory(2:end,:);
                update_TextApprentissage(app);
            end
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            % Enregistrement de la trajectoire en mémoire
            global nomTrajectoire trajectoire timeTrajectoire
            nom{1} = sprintf('%s',nomTrajectoire);
            nom = inputdlg('Entrer le nom de la trajectoire : ','Name input',1,nom);
            if ~isempty(nom)
               nomTrajectoire = nom{1};
                update_TextApprentissage(app);
                fileName = strcat('Trajectoires\', nomTrajectoire,'.dat');
                dlmwrite(fileName, [[timeTrajectoire, zeros(1,12)]; trajectoire]);
            end
            d = dir('.\Trajectoires\*.dat');
            names = {d.name};
            if size(names) ~=0
                app.TrajectoireList.Items = names;
            end
           if ~isempty(app.TrajectoireList.Value)
               fileName = strcat('./Trajectoires/', app.TrajectoireList.Value);
               timeAndTrajectory = dlmread(fileName);
               timeTrajectory= timeAndTrajectory(1,1);
               app.TempsentrelespointsEditField.Value = num2str(timeTrajectory);
           end
        end

        % Value changed function: ParamSelectionDDL
        function ParamSelectionDDLValueChanged(app, event)
            % Mise à jour de l'affichage des paramètres du robot lors que
            % la sélection des DDLs change
            updateParamData(app);
        end

        % Value changed function: VitesseMaximaleEditField
        function VitesseMaximaleEditFieldValueChanged(app, event)
            % Modification de la vitesse maximal (session en cours
            % seulement)
            global ddl_active
            if sum(ddl_active) > 0
                app.Vmax(ddl_active) = app.VitesseMaximaleEditField.Value;
                set_Vmax(app.Vmax);
                updateSystemState(app);
                % updateParamData(app) to do : update selection
            end
        end

        % Value changed function: CourantdedmarEditField
        function CourantdedmarEditFieldValueChanged(app, event)
            % Modification du courant de démarrage (session en cours
            % seulement)
            global ddl_active
            if sum(ddl_active) > 0
                app.Idemar(ddl_active) = app.CourantdedmarEditField.Value;
                set_Idemar(app.Idemar);
                updateSystemState(app);
            end
        end

        % Value changed function: CouplePEditField
        function CouplePEditFieldValueChanged(app, event)
            % Modification du paramètre P du PID du couple (session en cours
            % seulement)
            global ddl_active
            if sum(ddl_active) > 0
                app.CoupleP(ddl_active) = app.CouplePEditField.Value;
                set_CoupleP(app.CoupleP);
                updateSystemState(app);
            end
        end

        % Value changed function: CoupleIEditField
        function CoupleIEditFieldValueChanged(app, event)
            % Modification du paramètre I du PID du couple (session en cours
            % seulement)
            global ddl_active
            if sum(ddl_active) > 0
                app.CoupleI(ddl_active) = app.CoupleIEditField.Value;
                set_CoupleI(app.CoupleI);
                updateSystemState(app);
            end
        end

        % Value changed function: VitessePEditField
        function VitessePEditFieldValueChanged(app, event)
            % Modification du paramètre P du PID en vitesse (session en cours
            % seulement)
            global ddl_active
            if sum(ddl_active) > 0
                app.VitesseP(ddl_active) = app.VitessePEditField.Value;
                set_VitesseP(app.VitesseP);
                updateSystemState(app);
            end
        end

        % Value changed function: VitesseIEditField
        function VitesseIEditFieldValueChanged(app, event)
            % Modification du paramètre I du PID en vitesse (session en cours
            % seulement)
            global ddl_active
            if sum(ddl_active) > 0
                app.VitesseI(ddl_active) = app.VitesseIEditField.Value;
                set_VitesseI(app.VitesseI);
                updateSystemState(app);
            end
        end

        % Value changed function: PositionPEditField
        function PositionPEditFieldValueChanged(app, event)
            % Modification du paramètre P du PID en position (session en cours
            % seulement)
            global ddl_active
            if sum(ddl_active) > 0
                app.PositionP(ddl_active) = app.PositionPEditField.Value;
                set_PositionP(app.PositionP);
                updateSystemState(app);
            end
        end

        % Button pushed function: DmarrageButton
        function DmarrageButtonPushed(app, event)
         % Démarrage du programme assembleur dans les contrôleurs
         % Deux types de démarrage sont possibles: avec ou sans calibration
         global enabled_ddl initState demarrageState
         if initState == 1
             % wait 
             init_moteurs(app);
             updateParamData(app);            
             choice = menu('Type de démarrage','Sans Calibration','Avec Calibration');
             if choice == 1
                pause(2);
                skipCalib();
             elseif choice == 2
                pause(2);
                startCalib();
             end
             demarrageState = 1;
         else
             printToTerminal(app, "Initialiser les communication avec le bouton Initialisation avant d'effectuer un démarrage")
         end
        
        end

        % Button pushed function: ArrtButton
        function ArrtButtonPushed(app, event)
            % Mise à jour de la position actuelle du robot comme 0 (bras à
            % l'horiziontale) pour éviter un mouvement brusque lors de la
            % réactivation du robot et arrêt du programme des contrôleurs de robot
            setActualPositionTo0();
            stopApplication();
        end

        % Button pushed function: 
        % EnregistrementdesnouveauxparametresButton
        function EnregistrementdesnouveauxparametresButtonPushed(app, event)
            % Enregistrement dans le programme assembleur des contrôleurs
            % de la valeurs des paramètres de la session en cours
            stopApplication();
            udapteParametresControleurs(app);
        end

        % Button pushed function: EnregistrercommezroButton
        function EnregistrercommezroButtonPushed(app, event)
            % Actualiser la position actuelle comme le nouveau zéro (bras
            % à l'horizontal)
            global q  RobotC
            setActualPositionTo0();
            q = [0 pi/2 0 0 0 0 0];
            update_q(app);
            CineDirect(app);
            update_x(app);
            plotCrisp(app);
            % RobotC.plot(q);
            printToTerminal(app, "Position initiale actualisée.")
        end

        % Button pushed function: EnregistrercommezroButton_2
        function EnregistrercommezroButton_2Pushed(app, event)
            % Actualiser la position actuelle comme le nouveau zéro (bras
            % à l'horizontal)
            global q  RobotC
            setActualPositionTo0();
            q = [0 pi/2 0 0 0 0 0];
            update_q(app);
            CineDirect(app);
            update_x(app);
            plotCrisp(app);
            % RobotC.plot(q);
            printToTerminal(app, "Position initiale actualisée.")
        end

        % Value changed function: TrajectoireList
        function TrajectoireListValueChanged(app, event)
           % Changement de sélection dans la lsite de trajectoire
           global commandTrajectory commandTimeTrajectory 
           fileName = strcat('./Trajectoires/', app.TrajectoireList.Value);
           timeAndTrajectory = dlmread(fileName);
           commandTimeTrajectory = timeAndTrajectory(1,1);
           app.TempsentrelespointsEditField.Value = num2str(commandTimeTrajectory);
           commandTrajectory = timeAndTrajectory(2:end,:);
        end

        % Value changed function: LoopingSwitch
        function LoopingSwitchValueChanged(app, event)
            % Sélection du mode de trajectoire (en boucle infinie ou une
            % fois)
            global loopingMode
            if strcmp(app.LoopingSwitch.Value, "En boucle")
            loopingMode = 10;
            else 
            loopingMode = 0;
            end
        end

        % Button pushed function: JouerlatrajectoireButton
        function JouerlatrajectoireButtonPushed(app, event)
            % Enregistrer les points dans la mémoire des contrôleurs
            % Commencer à jouer la trajectoire 
            startTrajectoryCommands();
            printToTerminal(app,"Trajectoire déloyée");
        end

        % Button pushed function: ArrterlatrajectoireButton
        function ArrterlatrajectoireButtonPushed(app, event)
            % Arrêter la trajectoire après le cycle en cours 
            StopTrajectoryCommands();
            printToTerminal(app,"Trajectoire annulée");
        end

        % Value changed function: EnregistementSwitch
        function EnregistementSwitchValueChanged(app, event)
            %%% Première étape : Trouver les variables selectionnees dans
            % l'interface
                % Prends en entree les switch activees
                % Le programmeur doit ajouter les nouvelles entrées dans
                % la variable "ToRecordMap"
         global ToRecord
         global ToRecordMap
         
                ToRecordMap=["Position", "Vitesse","Acceleration"];
                ToRecord=strings(size(ToRecordMap));
                    for i= 1:size(ToRecord,2)
                        % Execution dans boite de cmd de par exemple : 
                        % ToRecord(i)=app.AccelerationSwitch.Value;
                        eval(['ToRecord(i)=app.', char(ToRecordMap(i)) , 'Switch.Value;' ]);

                    end
            % Deuxieme étape : overture du fichier texte d'enregistrement
            % des données écriture des donnees par la fonction RecordTimerCallbackFunction.
            global FileID_RecordTimer
            global enabled_ddl
            global NomFichierRecord
            global temps_init
            global firstloopDone
            NomFichierRecord=app.NomdufichierEditField.Value;
            value = app.EnregistementSwitch.Value;
            FileID_RecordTimer=fopen(strcat(NomFichierRecord,'.bat'),'a');
            
            if value=="On"
                fprintf(FileID_RecordTimer, 'Variables selectionnées pour l''enregistrement de données : \n');
                           for i=1:size(ToRecord,2)
                                if ToRecord(i)=="On"
                                    fprintf(FileID_RecordTimer, '%i) %s \n',i,ToRecordMap(i));
                                end
                           end
                fprintf(FileID_RecordTimer,'Degrés de liberté activés (1=Activé, 0=Fermé) : \n');
                fprintf(FileID_RecordTimer,'DL num%i = %i \t',[1:length(enabled_ddl);enabled_ddl]);
                fprintf(FileID_RecordTimer, '\n Data record started at %s\n ---- \n \n', datestr(now,'HH:MM:SS.FFF')) ;
                temps_init=now;
                firstloopDone=0;
                start(app.RecordTimer)
            else
               stop(app.RecordTimer) 
               fprintf(FileID_RecordTimer, '\n \n ---- \n Data record finished at %s\n', datestr(now,'HH:MM:SS.FFF')) ;
               status=fclose(FileID_RecordTimer);
               if status==-1
                   warning('Transfert du fichier de données au dossier voulu à échoué, une version additionnelle existe dans le dossier de travail actuel de matlab (pwd)')
               end
                % Copier le fichier écrit pendant l'enregistrement à l'endroit
                % spécifié par l'utilisateur
                % On vérifie d'abords si le fichier n'existe pas déjà
                if ~isfile(strcat(app.DossierEditField.Value, '\' ,NomFichierRecord,'.bat'))
                    copyfile(strcat(NomFichierRecord,'.bat'),app.DossierEditField.Value);
                elseif ~strcmp(pwd,app.DossierEditField.Value)
                    delete(strcat(app.DossierEditField.Value, '\' ,NomFichierRecord,'.bat'))
                    copyfile(strcat(NomFichierRecord,'.bat'),app.DossierEditField.Value);
                end            
                % Supprimer le fichier écrit pendant l'enregistrement à l'endroit
                % workspace acutel (pwd)
                if ~strcmp(pwd,app.DossierEditField.Value)
                    delete(strcat(NomFichierRecord,'.bat'))
                end
            end
        end

        % Value changed function: TouteslesvariablesSwitch
        function TouteslesvariablesSwitchValueChanged(app, event)
            % Activation de toutes les données à enregistrer
            value = app.TouteslesvariablesSwitch.Value;
            if value=="On"
                app.AccelerationSwitch.Value="On";
                app.VitesseSwitch.Value="On";
                app.PositionSwitch.Value="On";
            else
                app.AccelerationSwitch.Value="Off";
                app.VitesseSwitch.Value="Off";
                app.PositionSwitch.Value="Off";
            end
        end

        % Button pushed function: SlectionnerdossierButton
        function SlectionnerdossierButtonPushed(app, event)
            %Sélection du dossier à enregistrer les données
            selpath = uigetdir(path,'Choisir dans quel dossier enregistrer les données');
            app.DossierEditField.Value=selpath;
        end

        % Value changed function: NomdufichierEditField
        function NomdufichierEditFieldValueChanged(app, event)
            % Définition du nom du fichier de données
            value = app.NomdufichierEditField.Value;
            value = split(value,'.');
            value =string(value(1));
            
            app.NomdufichierEditField.Value=value;
            global NomFichierRecord
            NomFichierRecord=value;
        end

        % Button pushed function: GraphiquetempsrelButton
        function GraphiquetempsrelButtonPushed(app, event)
            % Ouverture du graphique des données en temps réel
            app.GraphiquetempsrelButton.Enable='off'; % Desactive le bouton, sera réactivé si on ferme la boîte de dialogue
            app.RealTimeGraphAppCrispV1= RealTimeGraphAppCrispV1(app);
        end

        % Close request function: 
        % InterfaceGraphiqueRobotCRISPUIFigure
        function InterfaceGraphiqueRobotCRISPUIFigureCloseRequest(app, event)
            % Destructeur de l'application
            % Exécuté lors de la fermeture de l'application
            delete(app.myTimer)
            delete(app.RecordTimer)
            delete(app.RealTimeGraphAppCrispV1)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterfaceGraphiqueRobotCRISPUIFigure = uifigure('Visible', 'off');
            app.InterfaceGraphiqueRobotCRISPUIFigure.Name = 'Interface Graphique : Robot CRISP';
            app.InterfaceGraphiqueRobotCRISPUIFigure.CloseRequestFcn = createCallbackFcn(app, @InterfaceGraphiqueRobotCRISPUIFigureCloseRequest, true);
            app.TextArea = uitextarea(app.InterfaceGraphiqueRobotCRISPUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'            '};
            app.InitialisationButton = uibutton(app.InterfaceGraphiqueRobotCRISPUIFigure, 'push');
            app.InitialisationButton.ButtonPushedFcn = createCallbackFcn(app, @InitialisationButtonPushed, true);
            app.InitialisationButton.Text = 'Initialisation';
            app.ArrtButton = uibutton(app.InterfaceGraphiqueRobotCRISPUIFigure, 'push');
            app.ArrtButton.ButtonPushedFcn = createCallbackFcn(app, @ArrtButtonPushed, true);
            app.ArrtButton.Text = 'Arrêt';
            app.Image = uiimage(app.InterfaceGraphiqueRobotCRISPUIFigure);
            app.Image.ImageSource = 'polytechnique_logo.png';
            app.TabGroup_2 = uitabgroup(app.InterfaceGraphiqueRobotCRISPUIFigure);
            app.CommandedirecteTab = uitab(app.TabGroup_2);
            app.CommandedirecteTab.Title = 'Commande directe';
            app.TabGroup = uitabgroup(app.CommandedirecteTab);
            app.CartsienTab = uitab(app.TabGroup);
            app.CartsienTab.Title = 'Cartésien';
            app.RoulisSlider_2Label = uilabel(app.CartsienTab);
            app.RoulisSlider_2Label.Text = 'Roulis';
            app.RoulisSlider = uislider(app.CartsienTab);
            app.RoulisSlider.Limits = [-4.71238898038469 4.71238898038469];
            app.RoulisSlider.MajorTicks = [];
            app.RoulisSlider.ValueChangedFcn = createCallbackFcn(app, @RoulisSliderValueChanged, true);
            app.RoulisSlider.MinorTicks = [];
            app.TangageSliderLabel = uilabel(app.CartsienTab);
            app.TangageSliderLabel.Text = 'Tangage';
            app.TangageSlider = uislider(app.CartsienTab);
            app.TangageSlider.Limits = [-4.71238898038469 4.71238898038469];
            app.TangageSlider.MajorTicks = [];
            app.TangageSlider.ValueChangedFcn = createCallbackFcn(app, @TangageSliderValueChanged, true);
            app.TangageSlider.MinorTicks = [];
            app.LacetSliderLabel = uilabel(app.CartsienTab);
            app.LacetSliderLabel.Text = 'Lacet';
            app.LacetSlider = uislider(app.CartsienTab);
            app.LacetSlider.Limits = [-4.71238898038469 4.71238898038469];
            app.LacetSlider.MajorTicks = [];
            app.LacetSlider.ValueChangedFcn = createCallbackFcn(app, @LacetSliderValueChanged, true);
            app.LacetSlider.MinorTicks = [];
            app.ZSliderLabel = uilabel(app.CartsienTab);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.CartsienTab);
            app.ZSlider.Limits = [-1200 1200];
            app.ZSlider.MajorTicks = [];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.MinorTicks = [];
            app.YSliderLabel = uilabel(app.CartsienTab);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.CartsienTab);
            app.YSlider.Limits = [-1200 1200];
            app.YSlider.MajorTicks = [];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.YSlider.MinorTicks = [];
            app.XSliderLabel = uilabel(app.CartsienTab);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.CartsienTab);
            app.XSlider.Limits = [-1200 1200];
            app.XSlider.MajorTicks = [];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.MinorTicks = [];
            app.PositioninitialeButton = uibutton(app.CartsienTab, 'push');
            app.PositioninitialeButton.ButtonPushedFcn = createCallbackFcn(app, @PositioninitialeButtonPushed, true);
            app.PositioninitialeButton.Text = 'Position initiale';
            app.mmLabel = uilabel(app.CartsienTab);
            app.mmLabel.Text = 'mm';
            app.mmEditField = uieditfield(app.CartsienTab, 'numeric');
            app.mmEditField.ValueDisplayFormat = '%5.1f';
            app.mmEditField.ValueChangedFcn = createCallbackFcn(app, @mmEditFieldValueChanged, true);
            app.mmEditField_2Label = uilabel(app.CartsienTab);
            app.mmEditField_2Label.Text = 'mm';
            app.mmEditField_2 = uieditfield(app.CartsienTab, 'numeric');
            app.mmEditField_2.ValueDisplayFormat = '%5.1f';
            app.mmEditField_2.ValueChangedFcn = createCallbackFcn(app, @mmEditField_2ValueChanged, true);
            app.mmEditField_3Label = uilabel(app.CartsienTab);
            app.mmEditField_3Label.Text = 'mm';
            app.mmEditField_3 = uieditfield(app.CartsienTab, 'numeric');
            app.mmEditField_3.ValueDisplayFormat = '%5.1f';
            app.mmEditField_3.ValueChangedFcn = createCallbackFcn(app, @mmEditField_3ValueChanged, true);
            app.EditField_10Label = uilabel(app.CartsienTab);
            app.EditField_10Label.Text = '°';
            app.EditField_10 = uieditfield(app.CartsienTab, 'numeric');
            app.EditField_10.ValueDisplayFormat = '%3.1f';
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_11Label = uilabel(app.CartsienTab);
            app.EditField_11Label.Text = '°';
            app.EditField_11 = uieditfield(app.CartsienTab, 'numeric');
            app.EditField_11.ValueDisplayFormat = '%3.1f';
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_12Label = uilabel(app.CartsienTab);
            app.EditField_12Label.Text = '°';
            app.EditField_12 = uieditfield(app.CartsienTab, 'numeric');
            app.EditField_12.ValueDisplayFormat = '%3.1f';
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EnregistrercommezroButton = uibutton(app.CartsienTab, 'push');
            app.EnregistrercommezroButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrercommezroButtonPushed, true);
            app.EnregistrercommezroButton.Text = {'Enregistrer '; 'comme zéro'};
            app.ArticulaireTab = uitab(app.TabGroup);
            app.ArticulaireTab.Title = 'Articulaire';
            app.Slider_4Label = uilabel(app.ArticulaireTab);
            app.Slider_4Label.Text = 'θ4';
            app.Slider_4 = uislider(app.ArticulaireTab);
            app.Slider_4.Limits = [-1.5708 1.5708];
            app.Slider_4.MajorTicks = [];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            app.Slider_4.MinorTicks = [];
            app.Slider_4.Value = 0.785398163397448;
            app.Slider_5Label = uilabel(app.ArticulaireTab);
            app.Slider_5Label.Text = 'θ5';
            app.Slider_5 = uislider(app.ArticulaireTab);
            app.Slider_5.Limits = [-3.1416 3.1416];
            app.Slider_5.MajorTicks = [];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.ValueChangingFcn = createCallbackFcn(app, @Slider_5ValueChanging, true);
            app.Slider_5.MinorTicks = [];
            app.Slider_6Label = uilabel(app.ArticulaireTab);
            app.Slider_6Label.Text = 'θ6';
            app.Slider_6 = uislider(app.ArticulaireTab);
            app.Slider_6.Limits = [-1.5708 1.5708];
            app.Slider_6.MajorTicks = [];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.ValueChangingFcn = createCallbackFcn(app, @Slider_6ValueChanging, true);
            app.Slider_6.MinorTicks = [];
            app.Slider_3Label = uilabel(app.ArticulaireTab);
            app.Slider_3Label.Text = 'θ3';
            app.Slider_3 = uislider(app.ArticulaireTab);
            app.Slider_3.Limits = [-3.1416 3.1416];
            app.Slider_3.MajorTicks = [];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Slider_3.MinorTicks = [];
            app.Slider_2Label = uilabel(app.ArticulaireTab);
            app.Slider_2Label.Text = 'θ2';
            app.Slider_2 = uislider(app.ArticulaireTab);
            app.Slider_2.Limits = [0.78538 3.1416];
            app.Slider_2.MajorTicks = [];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_2.Value = 0.785398163397448;
            app.SliderLabel = uilabel(app.ArticulaireTab);
            app.SliderLabel.Text = 'θ1';
            app.Slider = uislider(app.ArticulaireTab);
            app.Slider.Limits = [-1.5707963267949 1.5707963267949];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.PositioninitialeButton_2 = uibutton(app.ArticulaireTab, 'push');
            app.PositioninitialeButton_2.ButtonPushedFcn = createCallbackFcn(app, @PositioninitialeButton_2Pushed, true);
            app.PositioninitialeButton_2.Text = 'Position initiale';
            app.EditFieldLabel = uilabel(app.ArticulaireTab);
            app.EditFieldLabel.Text = '°';
            app.EditField = uieditfield(app.ArticulaireTab, 'numeric');
            app.EditField.ValueDisplayFormat = '%3.1f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2Label = uilabel(app.ArticulaireTab);
            app.EditField_2Label.Text = '°';
            app.EditField_2 = uieditfield(app.ArticulaireTab, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%3.1f';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3Label = uilabel(app.ArticulaireTab);
            app.EditField_3Label.Text = '°';
            app.EditField_3 = uieditfield(app.ArticulaireTab, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%3.1f';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4Label = uilabel(app.ArticulaireTab);
            app.EditField_4Label.Text = '°';
            app.EditField_4 = uieditfield(app.ArticulaireTab, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%3.1f';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5Label = uilabel(app.ArticulaireTab);
            app.EditField_5Label.Text = '°';
            app.EditField_5 = uieditfield(app.ArticulaireTab, 'numeric');
            app.EditField_5.ValueDisplayFormat = '%3.1f';
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6Label = uilabel(app.ArticulaireTab);
            app.EditField_6Label.Text = '°';
            app.EditField_6 = uieditfield(app.ArticulaireTab, 'numeric');
            app.EditField_6.ValueDisplayFormat = '%3.1f';
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EnregistrercommezroButton_2 = uibutton(app.ArticulaireTab, 'push');
            app.EnregistrercommezroButton_2.ButtonPushedFcn = createCallbackFcn(app, @EnregistrercommezroButton_2Pushed, true);
            app.EnregistrercommezroButton_2.Text = {'Enregistrer '; 'comme zéro'};
            app.TrajectoireTab = uitab(app.TabGroup_2);
            app.TrajectoireTab.Title = 'Trajectoire';
            app.TrajectoireList = uilistbox(app.TrajectoireTab);
            app.TrajectoireList.Items = {};
            app.TrajectoireList.ValueChangedFcn = createCallbackFcn(app, @TrajectoireListValueChanged, true);
            app.TrajectoireList.Value = {};
            app.LoopingSwitch = uiswitch(app.TrajectoireTab, 'slider');
            app.LoopingSwitch.Items = {'En boucle', 'Une fois'};
            app.LoopingSwitch.ValueChangedFcn = createCallbackFcn(app, @LoopingSwitchValueChanged, true);
            app.LoopingSwitch.Value = 'En boucle';
            app.JouerlatrajectoireButton = uibutton(app.TrajectoireTab, 'push');
            app.JouerlatrajectoireButton.ButtonPushedFcn = createCallbackFcn(app, @JouerlatrajectoireButtonPushed, true);
            app.JouerlatrajectoireButton.Text = {'Jouer '; 'la trajectoire'};
            app.TempsentrelespointsEditFieldLabel = uilabel(app.TrajectoireTab);
            app.TempsentrelespointsEditFieldLabel.Text = {'Temps entre '; 'les points:'};
            app.TempsentrelespointsEditField = uieditfield(app.TrajectoireTab, 'text');
            app.TempsentrelespointsEditField.Editable = 'off';
            app.ArrterlatrajectoireButton = uibutton(app.TrajectoireTab, 'push');
            app.ArrterlatrajectoireButton.ButtonPushedFcn = createCallbackFcn(app, @ArrterlatrajectoireButtonPushed, true);
            app.ArrterlatrajectoireButton.Text = {'Arrêter'; 'la trajectoire'};
            app.TabGroup2 = uitabgroup(app.InterfaceGraphiqueRobotCRISPUIFigure);
            app.DegrsdelibertTab_2 = uitab(app.TabGroup2);
            app.DegrsdelibertTab_2.Title = 'Degrés de liberté';
            app.PectoralLabel = uilabel(app.DegrsdelibertTab_2);
            app.PectoralLabel.Text = '1)   Pectoral';
            app.PectoralSwitch = uiswitch(app.DegrsdelibertTab_2, 'slider');
            app.PectoralSwitch.ValueChangedFcn = createCallbackFcn(app, @PectoralSwitchValueChanged, true);
            app.pauleLabel = uilabel(app.DegrsdelibertTab_2);
            app.pauleLabel.Text = '2)   Épaule';
            app.EpauleSwitch = uiswitch(app.DegrsdelibertTab_2, 'slider');
            app.EpauleSwitch.ValueChangedFcn = createCallbackFcn(app, @EpauleSwitchValueChanged, true);
            app.BrasLabel = uilabel(app.DegrsdelibertTab_2);
            app.BrasLabel.Text = '3)   Bras';
            app.BrasSwitch = uiswitch(app.DegrsdelibertTab_2, 'slider');
            app.BrasSwitch.ValueChangedFcn = createCallbackFcn(app, @BrasSwitchValueChanged, true);
            app.CoudeLabel = uilabel(app.DegrsdelibertTab_2);
            app.CoudeLabel.Text = '4)   Coude';
            app.CoudeSwitch = uiswitch(app.DegrsdelibertTab_2, 'slider');
            app.CoudeSwitch.ValueChangedFcn = createCallbackFcn(app, @CoudeSwitchValueChanged, true);
            app.AvantbrasLabel = uilabel(app.DegrsdelibertTab_2);
            app.AvantbrasLabel.Text = '5)   Avant-bras';
            app.AvantbrasSwitch = uiswitch(app.DegrsdelibertTab_2, 'slider');
            app.AvantbrasSwitch.ValueChangedFcn = createCallbackFcn(app, @AvantbrasSwitchValueChanged, true);
            app.PoignetLabel = uilabel(app.DegrsdelibertTab_2);
            app.PoignetLabel.Text = '6)   Poignet';
            app.PoignetSwitch = uiswitch(app.DegrsdelibertTab_2, 'slider');
            app.PoignetSwitch.ValueChangedFcn = createCallbackFcn(app, @PoignetSwitchValueChanged, true);
            app.MainLabel = uilabel(app.DegrsdelibertTab_2);
            app.MainLabel.Text = '7)   Main';
            app.OutilSwitch = uiswitch(app.DegrsdelibertTab_2, 'slider');
            app.OutilSwitch.ValueChangedFcn = createCallbackFcn(app, @OutilSwitchValueChanged, true);
            app.TouslesDDLSwitchLabel = uilabel(app.DegrsdelibertTab_2);
            app.TouslesDDLSwitchLabel.Text = 'Tous les DDL';
            app.TouslesDDLSwitch = uiswitch(app.DegrsdelibertTab_2, 'slider');
            app.TouslesDDLSwitch.ValueChangedFcn = createCallbackFcn(app, @TouslesDDLSwitchValueChanged, true);
            app.ParamtresTab_3 = uitab(app.TabGroup2);
            app.ParamtresTab_3.Title = 'Paramètres';
            app.VitesseMaximaleEditField_2Label = uilabel(app.ParamtresTab_3);
            app.VitesseMaximaleEditField_2Label.Text = 'Vitesse Maximale';
            app.VitesseMaximaleEditField = uieditfield(app.ParamtresTab_3, 'numeric');
            app.VitesseMaximaleEditField.ValueChangedFcn = createCallbackFcn(app, @VitesseMaximaleEditFieldValueChanged, true);
            app.ParamSelectionDDL = uidropdown(app.ParamtresTab_3);
            app.ParamSelectionDDL.Items = {'Pectoral', 'Épaule', 'Bras', 'Coude', 'Avant-bras', 'Poignet'};
            app.ParamSelectionDDL.ValueChangedFcn = createCallbackFcn(app, @ParamSelectionDDLValueChanged, true);
            app.ParamSelectionDDL.Value = 'Pectoral';
            app.CourantdedmarEditField_2Label = uilabel(app.ParamtresTab_3);
            app.CourantdedmarEditField_2Label.Text = 'Courant de démar.';
            app.CourantdedmarEditField = uieditfield(app.ParamtresTab_3, 'numeric');
            app.CourantdedmarEditField.ValueChangedFcn = createCallbackFcn(app, @CourantdedmarEditFieldValueChanged, true);
            app.CouplePEditField_2Label = uilabel(app.ParamtresTab_3);
            app.CouplePEditField_2Label.Text = 'Couple P';
            app.CouplePEditField = uieditfield(app.ParamtresTab_3, 'numeric');
            app.CouplePEditField.ValueChangedFcn = createCallbackFcn(app, @CouplePEditFieldValueChanged, true);
            app.CoupleIEditField_2Label = uilabel(app.ParamtresTab_3);
            app.CoupleIEditField_2Label.Text = 'Couple I';
            app.CoupleIEditField = uieditfield(app.ParamtresTab_3, 'numeric');
            app.CoupleIEditField.ValueChangedFcn = createCallbackFcn(app, @CoupleIEditFieldValueChanged, true);
            app.VitessePEditField_2Label = uilabel(app.ParamtresTab_3);
            app.VitessePEditField_2Label.Text = 'Vitesse P';
            app.VitessePEditField = uieditfield(app.ParamtresTab_3, 'numeric');
            app.VitessePEditField.ValueChangedFcn = createCallbackFcn(app, @VitessePEditFieldValueChanged, true);
            app.VitesseIEditField_2Label = uilabel(app.ParamtresTab_3);
            app.VitesseIEditField_2Label.Text = 'Vitesse I';
            app.VitesseIEditField = uieditfield(app.ParamtresTab_3, 'numeric');
            app.VitesseIEditField.ValueChangedFcn = createCallbackFcn(app, @VitesseIEditFieldValueChanged, true);
            app.PositionPEditField_2Label = uilabel(app.ParamtresTab_3);
            app.PositionPEditField_2Label.Text = 'Position P';
            app.PositionPEditField = uieditfield(app.ParamtresTab_3, 'numeric');
            app.PositionPEditField.ValueChangedFcn = createCallbackFcn(app, @PositionPEditFieldValueChanged, true);
            app.EnregistrementdesnouveauxparametresButton = uibutton(app.ParamtresTab_3, 'push');
            app.EnregistrementdesnouveauxparametresButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrementdesnouveauxparametresButtonPushed, true);
            app.EnregistrementdesnouveauxparametresButton.Text = 'Enregistrement des nouveaux parametres';
            app.CommunicationTab = uitab(app.TabGroup2);
            app.CommunicationTab.Title = 'Communication';
            app.PortEditFieldLabel = uilabel(app.CommunicationTab);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.CommunicationTab, 'text');
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Value = 'COM14';
            app.TabGroup2_2 = uitabgroup(app.InterfaceGraphiqueRobotCRISPUIFigure);
            app.ApprentissageTab_3 = uitab(app.TabGroup2_2);
            app.ApprentissageTab_3.Title = 'Apprentissage';
            app.ApprentissagePos = uitextarea(app.ApprentissageTab_3);
            app.ApprentissagePos.Editable = 'off';
            app.ApprentissagePos.Tooltip = {'Effacer la dernière position'};
            app.RecPos = uibutton(app.ApprentissageTab_3, 'push');
            app.RecPos.ButtonPushedFcn = createCallbackFcn(app, @RecPosButtonPushed, true);
            app.RecPos.Icon = 'Add-Green-Button-icon.png';
            app.RecPos.Tooltip = {'Enregistrer la positiion'};
            app.RecPos.Text = '';
            app.Save = uibutton(app.ApprentissageTab_3, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Icon = 'Send-Document-icon.png';
            app.Save.Tooltip = {'Sauvegarder la trajectoire'};
            app.Save.Text = '';
            app.DeletePos = uibutton(app.ApprentissageTab_3, 'push');
            app.DeletePos.ButtonPushedFcn = createCallbackFcn(app, @DeletePosButtonPushed, true);
            app.DeletePos.Icon = 'Minus-Red-Button-icon.png';
            app.DeletePos.Tooltip = {'Supprimer la dernière position'};
            app.DeletePos.Text = '';
            app.SetTime = uibutton(app.ApprentissageTab_3, 'push');
            app.SetTime.ButtonPushedFcn = createCallbackFcn(app, @SetTimeButtonPushed, true);
            app.SetTime.Icon = 'Internet-History-icon.png';
            app.SetTime.Tooltip = {'Modifier le temps d''exécution des points de la trajectoire'};
            app.SetTime.Text = '';
            app.Play = uibutton(app.ApprentissageTab_3, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.Play.Icon = 'Play-icon.png';
            app.Play.Tooltip = {'Jouer les positions enregistrées'};
            app.Play.Text = '';
            app.Download = uibutton(app.ApprentissageTab_3, 'push');
            app.Download.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.Download.Icon = 'Import-Document-icon.png';
            app.Download.Tooltip = {'Importer une trajectoire'};
            app.Download.Text = '';
            app.LabelTrajectory = uilabel(app.ApprentissageTab_3);
            app.LabelTrajectory.Text = 'LabelTrajectory';
            app.DonnesTab = uitab(app.TabGroup2_2);
            app.DonnesTab.Title = 'Données';
            app.PositionSwitchLabel = uilabel(app.DonnesTab);
            app.PositionSwitchLabel.Text = '1)   Position';
            app.PositionSwitch = uiswitch(app.DonnesTab, 'slider');
            app.AcclrationLabel = uilabel(app.DonnesTab);
            app.AcclrationLabel.Text = '3)   Accélération';
            app.AccelerationSwitch = uiswitch(app.DonnesTab, 'slider');
            app.VitesseLabel = uilabel(app.DonnesTab);
            app.VitesseLabel.Text = '2)   Vitesse';
            app.VitesseSwitch = uiswitch(app.DonnesTab, 'slider');
            app.TouteslesvariablesSwitchLabel = uilabel(app.DonnesTab);
            app.TouteslesvariablesSwitchLabel.Text = 'Toutes les variables';
            app.TouteslesvariablesSwitch = uiswitch(app.DonnesTab, 'slider');
            app.TouteslesvariablesSwitch.ValueChangedFcn = createCallbackFcn(app, @TouteslesvariablesSwitchValueChanged, true);
            app.EnregistementSwitchLabel = uilabel(app.DonnesTab);
            app.EnregistementSwitchLabel.Text = 'Enregistement';
            app.EnregistementSwitch = uiswitch(app.DonnesTab, 'toggle');
            app.EnregistementSwitch.ValueChangedFcn = createCallbackFcn(app, @EnregistementSwitchValueChanged, true);
            app.NomdufichierEditFieldLabel = uilabel(app.DonnesTab);
            app.NomdufichierEditFieldLabel.Text = 'Nom du fichier';
            app.NomdufichierEditField = uieditfield(app.DonnesTab, 'text');
            app.NomdufichierEditField.ValueChangedFcn = createCallbackFcn(app, @NomdufichierEditFieldValueChanged, true);
            app.DossierEditFieldLabel = uilabel(app.DonnesTab);
            app.DossierEditFieldLabel.Text = 'Dossier';
            app.DossierEditField = uieditfield(app.DonnesTab, 'text');
            app.SlectionnerdossierButton = uibutton(app.DonnesTab, 'push');
            app.SlectionnerdossierButton.ButtonPushedFcn = createCallbackFcn(app, @SlectionnerdossierButtonPushed, true);
            app.SlectionnerdossierButton.Text = 'Sélectionner dossier';
            app.GraphiquetempsrelButton = uibutton(app.DonnesTab, 'push');
            app.GraphiquetempsrelButton.ButtonPushedFcn = createCallbackFcn(app, @GraphiquetempsrelButtonPushed, true);
            app.GraphiquetempsrelButton.Text = 'Graphique temps réel';
            app.OutilTab = uitab(app.TabGroup2_2);
            app.OutilTab.Title = 'Outil';
            app.Image3 = uiimage(app.OutilTab);
            app.Image3.ImageSource = 'RobotCAD_pince.png';
            app.d7mmEditFieldLabel = uilabel(app.OutilTab);
            app.d7mmEditFieldLabel.Text = 'd7 (mm)';
            app.d7mmEditField = uieditfield(app.OutilTab, 'numeric');
            app.d7mmEditField.ValueChangedFcn = createCallbackFcn(app, @d7mmEditFieldValueChanged, true);
            app.a7mmEditFieldLabel = uilabel(app.OutilTab);
            app.a7mmEditFieldLabel.Text = 'a7 (mm)';
            app.a7mmEditField = uieditfield(app.OutilTab, 'numeric');
            app.a7mmEditField.ValueChangedFcn = createCallbackFcn(app, @a7mmEditFieldValueChanged, true);
            app.Switch = uiswitch(app.OutilTab, 'slider');
            app.Switch.Items = {'Pneumatique', 'Électrique'};
            app.Switch.Value = 'Pneumatique';
            app.CrispLogoImage = uiimage(app.InterfaceGraphiqueRobotCRISPUIFigure);
            app.CrispLogoImage.ImageSource = 'CRISP.png';
            app.MaininactiveButton = uibutton(app.InterfaceGraphiqueRobotCRISPUIFigure, 'state');
            app.MaininactiveButton.ValueChangedFcn = createCallbackFcn(app, @MaininactiveButtonValueChanged, true);
            app.MaininactiveButton.Text = 'Main inactive';
            app.tatdurobotLabel = uilabel(app.InterfaceGraphiqueRobotCRISPUIFigure);
            app.tatdurobotLabel.Text = 'État du robot';
            app.EtatDEL = uilamp(app.InterfaceGraphiqueRobotCRISPUIFigure);
            app.Terminal = uilistbox(app.InterfaceGraphiqueRobotCRISPUIFigure);
            app.Terminal.Items = {};
            app.Terminal.Value = {};
            app.DmarrageButton = uibutton(app.InterfaceGraphiqueRobotCRISPUIFigure, 'push');
            app.DmarrageButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrageButtonPushed, true);
            app.DmarrageButton.Text = 'Démarrage';
            % Show the figure after all components are created
            app.InterfaceGraphiqueRobotCRISPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crisp_App_V9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfaceGraphiqueRobotCRISPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfaceGraphiqueRobotCRISPUIFigure)
        end
    end
end