classdef RealTimeGraphAppCrispV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PectoralSwitchLabel             matlab.ui.control.Label
        PectoralSwitch                  matlab.ui.control.Switch
        pauleLabel                      matlab.ui.control.Label
        EpauleSwitch                    matlab.ui.control.Switch
        BrasSwitchLabel                 matlab.ui.control.Label
        BrasSwitch                      matlab.ui.control.Switch
        CoudeSwitchLabel                matlab.ui.control.Label
        CoudeSwitch                     matlab.ui.control.Switch
        AvantbrasSwitchLabel            matlab.ui.control.Label
        AvantbrasSwitch                 matlab.ui.control.Switch
        PoignetSwitchLabel              matlab.ui.control.Label
        PoignetSwitch                   matlab.ui.control.Switch
        SelectionnerlesdonnesafficherLabel  matlab.ui.control.Label
        DLsafficherSurlemmegrapheLabel  matlab.ui.control.Label
        MainSwitchLabel                 matlab.ui.control.Label
        MainSwitch                      matlab.ui.control.Switch
        RightPanel                      matlab.ui.container.Panel
        ParamtrestudierSurdesgraphesdiffrentsLabel  matlab.ui.control.Label
        CreerlesfiguresButton           matlab.ui.control.Button
        xtoutSwitch_2Label              matlab.ui.control.Label
        VitesseSwitch                   matlab.ui.control.Switch
        xtoutSwitchLabel                matlab.ui.control.Label
        AccelerationSwitch              matlab.ui.control.Switch
        xtoutLabel                      matlab.ui.control.Label
        PositionSwitch                  matlab.ui.control.Switch
        LongueurmemoiredonneesafficheesEditFieldLabel  matlab.ui.control.Label
        MemoryLength_CRISP              matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        MainApp % Main app object
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp; % Pour appeler l'application presente par MainApp
            global enabled_ddl
            %Heritage des DDLs actives
           
            ToPrintDDLMap=["Pectoral", "Epaule","Bras","Coude","Avantbras","Poignet","Main"];
            if size(enabled_ddl,2)==size(ToPrintDDLMap,2)
                ToPrintDDL=strings(size(ToPrintDDLMap));
                    for i= 1:size(ToPrintDDL,2)
                        if enabled_ddl(i)==1
                        eval(['app.',char(ToPrintDDLMap(i)) , 'Switch.Value="On";' ]);
                        end
                    end
            else
                warning('Ajout d''un DDL inconnu! Modifier la variable ToPrintDDLMap dans le code')
            end
            global ToRecord
            %Heritage des variables actives
           
            ToRecordMap=["Position", "Vitesse","Acceleration"];
            if size(ToRecord,2)==size(ToRecordMap,2)
                
                    for i= 1:size(ToRecordMap,2)
                        if strcmp(ToRecord(i),"On")
                        eval(['app.',char(ToRecordMap(i)) , 'Switch.Value="On";' ]);
                        end
                    end
            else
                warning('Ajout d''une variable inconnue! Modifier la variable ToRecordMap dans le code')
            end


            app.MemoryLength_CRISP.Value=200; % Valeur par défaut
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.GraphiquetempsrelButton.Enable='on';
            delete(app)
            
        end

        % Button pushed function: CreerlesfiguresButton
        function CreerlesfiguresButtonPushed(app, event)
            % Fonction qui sonde les boutons appuyés pour créer les
            % graphiques nécessaire
            
            %Desactiver le changement de longueur memoire (user doit fermer
            %le graphe et recommmencer
%             app.MemoryLength_CRISP.Enable='off';
                    global ToPrintDDL
                    global ToPrintDDLMap
                    global ToRecordGraph
                    global PlotHandlesFigureData_CRISP
                    global timevect
                    global dataMatrix
                    global compteur
                    compteur=0; % Compteur
                    figure(99);
                    zer=zeros(1,app.MemoryLength_CRISP.Value);
                    timevect=linspace(0,10,app.MemoryLength_CRISP.Value);
                    
            
            ToRecordMap=["Position", "Vitesse","Acceleration"];
                ToRecordG=strings(1,size(ToRecordMap,2));
                    for i= 1:size(ToRecordG,2)
                        % Execution dans boite de cmd de par exemple : 
                        % ToRecord(i)=app.AccelerationSwitch.Value;
                        eval(['ToRecordG(i)=app.', char(ToRecordMap(i)) , 'Switch.Value;' ]);
                    end
            
            ToPrintDDLMap=["Pectoral", "Epaule","Bras","Coude","Avantbras","Poignet","Main"];
                ToPrintDDL=strings(1,size(ToPrintDDLMap,2));
                    for i= 1:size(ToPrintDDL,2)
                        % Execution dans boite de cmd de par exemple : 
                        % ToRecord(i)=app.AccelerationSwitch.Value;
                        eval(['ToPrintDDL(i)=app.', char(ToPrintDDLMap(i)) , 'Switch.Value;' ]);

                    end
                   % Matrice vide que RecordTimerCallback va utiliser [nDDl x nVariablesAPrint x DonneesMax]
                    dataMatrix=zeros(sum(strcmp(ToPrintDDL,"On")),sum(strcmp(ToRecordG,"On")),app.MemoryLength_CRISP.Value);
                    %Créer les subplot en fonction des cases cochées: lignes
                    % de zéros, legendes et titres avant le RealTime
                    
                    ToRecordGraph=strings(1,sum(strcmp(ToRecordG,"On")));
                    j=0;
                        figure(99)
                        
                    for i= 1:size(ToRecordG,2)
                        if strcmp(ToRecordG(i),"On")
                            j=j+1; % indice décalé
                            subplot(sum(strcmp(ToRecordG,"On"))+1,1,j) % le dernier subplot aura la legende
                            ToRecordGraph(j)=ToRecordMap(i);
                            hold on
                            for k=1:sum(strcmp(ToPrintDDL,"On"))
                                plot(timevect,zer);
                            end
                            hold off
                            title(ToRecordMap(i))
                        end
                    end
                        
                    % Ajout de la legende pour tous les graphes!
                        subplot(sum(strcmp(ToRecordG,"On"))+1,1,sum(strcmp(ToRecordG,"On"))+1)
                        % En affichant (0,0) et en detruisant le plot on
                        % fait une legende pour tous les graphes
                        
                        hold on
                        for i=1:size(dataMatrix,1)
                            plot(squeeze(dataMatrix(i,1,1)),squeeze(dataMatrix(i,1,1)))
                        end
                        hold off
                        axis off
                        legend(ToPrintDDLMap(strcmp(ToPrintDDL,"On")))
                        
                        PlotHandlesFigureData_CRISP = findobj('type','line');
                        PlotHandlesFigureData_CRISP = flip(PlotHandlesFigureData_CRISP);

                           % Delete the dialog box  
                           app.MainApp.GraphiquetempsrelButton.Enable='on';
                           delete(app)
   
                    
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {351, 351};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {233, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PectoralSwitchLabel = uilabel(app.LeftPanel);
            app.PectoralSwitchLabel.Text = '1)   Pectoral';
            app.PectoralSwitch = uiswitch(app.LeftPanel, 'slider');
            app.pauleLabel = uilabel(app.LeftPanel);
            app.pauleLabel.Text = '2)   Épaule';
            app.EpauleSwitch = uiswitch(app.LeftPanel, 'slider');
            app.BrasSwitchLabel = uilabel(app.LeftPanel);
            app.BrasSwitchLabel.Text = '3)   Bras';
            app.BrasSwitch = uiswitch(app.LeftPanel, 'slider');
            app.CoudeSwitchLabel = uilabel(app.LeftPanel);
            app.CoudeSwitchLabel.Text = '4)   Coude';
            app.CoudeSwitch = uiswitch(app.LeftPanel, 'slider');
            app.AvantbrasSwitchLabel = uilabel(app.LeftPanel);
            app.AvantbrasSwitchLabel.Text = '5)   Avant-bras';
            app.AvantbrasSwitch = uiswitch(app.LeftPanel, 'slider');
            app.PoignetSwitchLabel = uilabel(app.LeftPanel);
            app.PoignetSwitchLabel.Text = '6)   Poignet';
            app.PoignetSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SelectionnerlesdonnesafficherLabel = uilabel(app.LeftPanel);
            app.SelectionnerlesdonnesafficherLabel.Text = 'Selectionner les données à afficher';
            app.DLsafficherSurlemmegrapheLabel = uilabel(app.LeftPanel);
            app.DLsafficherSurlemmegrapheLabel.Text = 'DLs à afficher [Sur le même graphe (↓)]';
            app.MainSwitchLabel = uilabel(app.LeftPanel);
            app.MainSwitchLabel.Text = '7)   Main';
            app.MainSwitch = uiswitch(app.LeftPanel, 'slider');
            app.RightPanel = uipanel(app.GridLayout);
            app.ParamtrestudierSurdesgraphesdiffrentsLabel = uilabel(app.RightPanel);
            app.ParamtrestudierSurdesgraphesdiffrentsLabel.Text = 'Paramètres à étudier [Sur des graphes différents(→)] ';
            app.CreerlesfiguresButton = uibutton(app.RightPanel, 'push');
            app.CreerlesfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CreerlesfiguresButtonPushed, true);
            app.CreerlesfiguresButton.Text = 'Creer les figures';
            app.xtoutSwitch_2Label = uilabel(app.RightPanel);
            app.xtoutSwitch_2Label.Text = 'x''(t) ou θ''(t)';
            app.VitesseSwitch = uiswitch(app.RightPanel, 'slider');
            app.xtoutSwitchLabel = uilabel(app.RightPanel);
            app.xtoutSwitchLabel.Text = 'x''''(t) ou θ''''(t)';
            app.AccelerationSwitch = uiswitch(app.RightPanel, 'slider');
            app.xtoutLabel = uilabel(app.RightPanel);
            app.xtoutLabel.Text = 'x''t) ou θ(t)';
            app.PositionSwitch = uiswitch(app.RightPanel, 'slider');
            app.LongueurmemoiredonneesafficheesEditFieldLabel = uilabel(app.RightPanel);
            app.LongueurmemoiredonneesafficheesEditFieldLabel.Text = 'Longueur memoire (donnees affichees)';
            app.MemoryLength_CRISP = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RealTimeGraphAppCrispV1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end